classdef Probe_Layout_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ProbeLayoutWindowUIFigure       matlab.ui.Figure
        Panel_2                         matlab.ui.container.Panel
        NrofProbeChannelEditField       matlab.ui.control.EditField
        NrofProbeChannelEditFieldLabel  matlab.ui.control.Label
        OnlyforsingleshanklinearprobedesignsLabel  matlab.ui.control.Label
        basedoninputsontheleftLabel     matlab.ui.control.Label
        CreateKilosortChannelmapLabel_2  matlab.ui.control.Label
        SaveChannelmapasmatforKilosortButton  matlab.ui.control.Button
        ChannelmapNameEditField         matlab.ui.control.EditField
        ChannelmapNameEditFieldLabel    matlab.ui.control.Label
        OptionalLabel                   matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        ProbeLayoutWindowLabel          matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        SaveChannelOrderButton          matlab.ui.control.Button
        ChannelOrderFormat1234LeaveemptyfornocostumorderEditField  matlab.ui.control.TextArea
        ChannelOrderFormat1234LeaveemptyfornocostumorderTextAreaLabel  matlab.ui.control.Label
        SetProbeInformationandcontinueButton  matlab.ui.control.Button
        ChannelSpacingumEditField       matlab.ui.control.EditField
        ChannelSpacingumEditFieldLabel  matlab.ui.control.Label
        NecessaryProbeInformationLabel  matlab.ui.control.Label
        LoadChannelOrderButton          matlab.ui.control.Button
    end

    
    properties (Access = public)
        Mainapp = []; % Description
        ExtractRecordingWindow = [];
        Load_Data_Window_Info = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, ExtractRecordingWindow)
            % Moce this window in the middle of thescreen
            movegui(app.ProbeLayoutWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.ExtractRecordingWindow = ExtractRecordingWindow;


        end

        % Button pushed function: LoadChannelOrderButton
        function LoadChannelOrderButtonPushed(app, event)
            cd(app.Mainapp.executableFolder);
            %% Load a .mat file containing a vector with numbers indicating the true position of a channel on the probe
            % See ReadMe file in the ChannelOrder folder of the GUI 
            stringtoshow = "Please select a .mat file containing your channel order (numbers).";
            f = msgbox(stringtoshow);
            % Use uiget to to select a .mat file
            [file, path] = uigetfile('*.mat', 'Select a .mat file');
            
            % Check if the user cancels the operation
            if isequal(file,0) || isequal(path,0)
                disp('Operation canceled.');
                return;
            end
            
            % Load the selected .mat file and save in the mainapp
            tempChOrder = load(fullfile(path, file));
            % Get the field names of the structure
            fieldNames = fieldnames(tempChOrder);
            fieldName = fieldNames{1};

            app.Load_Data_Window_Info.Channelorder = tempChOrder.(fieldName);

            %% Display the channelorder in the Window 

            %tempChOrder = app.Load_Data_Window_Info.Channelorder;
            texttoshow = sprintf('%d, ', app.Load_Data_Window_Info.Channelorder);
            % Remove the trailing comma and whitespace
            texttoshow(end-1:end) = [];

            app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value = texttoshow;

        end

        % Value changed function: ChannelSpacingumEditField
        function ChannelSpacingumEditFieldValueChanged(app, event)
            value = app.ChannelSpacingumEditField.Value;
            
            if isempty(value)
                app.SetProbeInformationandcontinueButton.Enable = "off";
            else
                app.SetProbeInformationandcontinueButton.Enable = "on";
            end

        end

        % Button pushed function: SetProbeInformationandcontinueButton
        function SetProbeInformationandcontinueButtonPushed(app, event)

            app.ExtractRecordingWindow.Load_Data_Window_Info.ChannelSpacing = str2double(app.ChannelSpacingumEditField.Value);

            if isempty(app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value)
                app.ExtractRecordingWindow.Load_Data_Window_Info.Channelorder = [];
            else
                % Split the string by commas
                split_array = strsplit(app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value{1}, ',');
                
                % Convert each element to double
                app.ExtractRecordingWindow.Load_Data_Window_Info.Channelorder = str2double(split_array);
            end

            delete(app);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Button pushed function: SaveChannelmapasmatforKilosortButton
        function SaveChannelmapasmatforKilosortButtonPushed(app, event)
            cd(app.Mainapp.executableFolder);

            if isempty(app.ChannelSpacingumEditField.Value)
                stringtoshow = "Error. Please first define a channel spacing on the left side of this window";
                msgbox(stringtoshow);
                return;
            end
            if isempty(app.ChannelmapNameEditField.Value)
                stringtoshow = "Error. Please first define a channel name on the right side of this window";
                msgbox(stringtoshow);
                return;
            else
                name = app.ChannelmapNameEditField.Value;
            end

            if isempty(app.NrofProbeChannelEditField.Value)
                stringtoshow = "Error. Please first define a channel number on the right side of this window";
                msgbox(stringtoshow);
                return;
            end
            
            NrChannel = str2double(app.NrofProbeChannelEditField.Value);

            xcoords = zeros(NrChannel,1);
            ycoords = 0:str2double(app.ChannelSpacingumEditField.Value):str2double(app.ChannelSpacingumEditField.Value)*NrChannel-str2double(app.ChannelSpacingumEditField.Value);

            ycoords = ycoords';
            kcoords = zeros(NrChannel,1)+1;
            connected = true(NrChannel,1);
            chanMap = 1:1:NrChannel;
            chanMap = chanMap';
            chanMap0ind = 0:1:NrChannel-1;
            chanMap0ind = chanMap0ind';

            % Prompt user for file save location and name
            [file, path] = uiputfile('*.mat', 'Save as');
            
            if isequal(file,0) || isequal(path,0)
                disp('User canceled the operation.');
                return;
            end
    
            % Construct the full file path
            filepath = fullfile(path, file);
            % Save the variables to the .mat file
            save(filepath, 'xcoords','ycoords','kcoords','chanMap','chanMap0ind','connected');
            stringtoshow = ['Channelmap saved to: ' filepath];
            msgbox(stringtoshow);
        end

        % Button pushed function: SaveChannelOrderButton
        function SaveChannelOrderButtonPushed(app, event)
            cd(app.Mainapp.executableFolder);
            
            if ~isempty(app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value)

                % Split the string by commas
                split_array = strsplit(app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField.Value{1}, ',');
                
                % Convert each element to double
                Channelorder = str2double(split_array);

                % Prompt user for file save location and name
                [file, path] = uiputfile('*.mat', 'Save as');
                
                if isequal(file,0) || isequal(path,0)
                    disp('User canceled the operation.');
                    return;
                end
        
                % Construct the full file path
                filepath = fullfile(path, file);
                % Save the variables to the .mat file
                save(filepath, 'Channelorder');
                stringtoshow = ['Channel Order saved to: ' filepath];
                msgbox(stringtoshow);
            else
                stringtoshow = 'No Channel Order specified in the text field "Channel Order"';
                msgbox(stringtoshow);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ProbeLayoutWindowUIFigure = uifigure('Visible', 'off');
            app.ProbeLayoutWindowUIFigure.Name = 'Probe Layout Window';
            app.ProbeLayoutWindowUIFigure.Icon = 'Logo.png';
            app.Panel = uipanel(app.ProbeLayoutWindowUIFigure);
            app.LoadChannelOrderButton = uibutton(app.Panel, 'push');
            app.LoadChannelOrderButton.ButtonPushedFcn = createCallbackFcn(app, @LoadChannelOrderButtonPushed, true);
            app.LoadChannelOrderButton.Text = 'Load Channel Order';
            app.NecessaryProbeInformationLabel = uilabel(app.Panel);
            app.NecessaryProbeInformationLabel.Text = 'Necessary Probe Information:';
            app.ChannelSpacingumEditFieldLabel = uilabel(app.Panel);
            app.ChannelSpacingumEditFieldLabel.Text = 'Channel Spacing [um]';
            app.ChannelSpacingumEditField = uieditfield(app.Panel, 'text');
            app.ChannelSpacingumEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSpacingumEditFieldValueChanged, true);
            app.SetProbeInformationandcontinueButton = uibutton(app.Panel, 'push');
            app.SetProbeInformationandcontinueButton.ButtonPushedFcn = createCallbackFcn(app, @SetProbeInformationandcontinueButtonPushed, true);
            app.SetProbeInformationandcontinueButton.Enable = 'off';
            app.SetProbeInformationandcontinueButton.Text = 'Set Probe Information and continue';
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderTextAreaLabel = uilabel(app.Panel);
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderTextAreaLabel.Text = {'Channel Order'; 'Format: 1,2,3,4'; 'Leave empty for no '; 'costum order'};
            app.ChannelOrderFormat1234LeaveemptyfornocostumorderEditField = uitextarea(app.Panel);
            app.SaveChannelOrderButton = uibutton(app.Panel, 'push');
            app.SaveChannelOrderButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChannelOrderButtonPushed, true);
            app.SaveChannelOrderButton.Text = 'Save Channel Order';
            app.ProbeLayoutWindowLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.ProbeLayoutWindowLabel.Text = 'Probe Layout Window';
            app.Image = uiimage(app.ProbeLayoutWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.ProbeLayoutWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.ProbeLayoutWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel_2 = uipanel(app.ProbeLayoutWindowUIFigure);
            app.OptionalLabel = uilabel(app.Panel_2);
            app.OptionalLabel.Text = 'Optional:';
            app.ChannelmapNameEditFieldLabel = uilabel(app.Panel_2);
            app.ChannelmapNameEditFieldLabel.Text = 'Channelmap Name';
            app.ChannelmapNameEditField = uieditfield(app.Panel_2, 'text');
            app.SaveChannelmapasmatforKilosortButton = uibutton(app.Panel_2, 'push');
            app.SaveChannelmapasmatforKilosortButton.ButtonPushedFcn = createCallbackFcn(app, @SaveChannelmapasmatforKilosortButtonPushed, true);
            app.SaveChannelmapasmatforKilosortButton.Text = 'Save Channelmap as .mat for Kilosort';
            app.CreateKilosortChannelmapLabel_2 = uilabel(app.Panel_2);
            app.CreateKilosortChannelmapLabel_2.Text = 'Create Kilosort Channelmap';
            app.basedoninputsontheleftLabel = uilabel(app.Panel_2);
            app.basedoninputsontheleftLabel.Text = '(based on inputs on the left)';
            app.OnlyforsingleshanklinearprobedesignsLabel = uilabel(app.Panel_2);
            app.OnlyforsingleshanklinearprobedesignsLabel.Text = 'Only for single shank linear probe designs!';
            app.NrofProbeChannelEditFieldLabel = uilabel(app.Panel_2);
            app.NrofProbeChannelEditFieldLabel.Text = 'Nr of Probe Channel';
            app.NrofProbeChannelEditField = uieditfield(app.Panel_2, 'text');
            % Show the figure after all components are created
            app.ProbeLayoutWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Probe_Layout_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.ProbeLayoutWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.ProbeLayoutWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ProbeLayoutWindowUIFigure)
        end
    end
end