classdef CSD_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CSDAnalysisUIFigure            matlab.ui.Figure
        SnapshotMenu                   matlab.ui.container.Menu
        SaveSnapshotofWindowMenu       matlab.ui.container.Menu
        PNGMenu                        matlab.ui.container.Menu
        JPGMenu                        matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        Show2DimensionalPlotsMenu      matlab.ui.container.Menu
        Show3DimensionalPlotsMenu      matlab.ui.container.Menu
        ExportDataasMenu               matlab.ui.container.Menu
        matMenu                        matlab.ui.container.Menu
        txtMenu                        matlab.ui.container.Menu
        csvMenu                        matlab.ui.container.Menu
        CSDParameterPanel              matlab.ui.container.Panel
        AutoClimButton                 matlab.ui.control.Button
        DataTypeDropDown               matlab.ui.control.DropDown
        DataTypeDropDownLabel          matlab.ui.control.Label
        EventNumberSelectionEditField  matlab.ui.control.EditField
        EventNumberSelectionEditFieldLabel  matlab.ui.control.Label
        ClimminmaxEditField            matlab.ui.control.EditField
        ClimminmaxEditFieldLabel       matlab.ui.control.Label
        SurfaceChannelDropDown_2       matlab.ui.control.DropDown
        SurfaceChannelDropDown_2Label  matlab.ui.control.Label
        HammWindowEditFieldLabel       matlab.ui.control.Label
        HammWindowEditField            matlab.ui.control.EditField
        CloseButton                    matlab.ui.control.Button
        NeuromodToolboxLabel           matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        CurrentSourceDensityWindowLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu3                   matlab.ui.container.ContextMenu
        ChangeTitleMenu                matlab.ui.container.Menu
        ChangeXLabelMenu               matlab.ui.container.Menu
        ChangeYLabelMenu               matlab.ui.container.Menu
        ChangeXlimMenu                 matlab.ui.container.Menu
        ChangeYlimMenu                 matlab.ui.container.Menu
        ChangeLegendMenu               matlab.ui.container.Menu
        ChangeFontsizeMenu             matlab.ui.container.Menu
    end

    
    properties (Access = public)
        EventRelatedWindow = []; % Description
        climCSD = [];
        EventTime = [];
        TimearoundEvent = [];
        ChannelSelection = [];
        TwoORThreeD = "TwoD"; % standard 2d plots when possible
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EventRelatedWindow)
            movegui(app.CSDAnalysisUIFigure, 'center');

            app.EventRelatedWindow = EventRelatedWindow;

            if ~isfield(app.EventRelatedWindow.Mainapp.Data,'Events')
                msgbox("No events extracted. Please extract events first!. Exiting.");
                delete(app);
                return;
            end

            %% Fill Event Channel Selection, Event umber selection and channel selection fields

            if isfield(app.EventRelatedWindow.Mainapp.Data,'PreprocessedEventRelatedData')
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
                app.DataTypeDropDown.Items{2} = 'Preprocessed Event Related Data';
            else
                app.DataTypeDropDown.Items{1} = 'Raw Event Related Data';
            end

           % Surface Channel
           app.ChannelSelection(1) = 1;

            texttoshow = {};
            texttoshow{1} = 'Non';
            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,'Raw')
                for i = 1:size(app.EventRelatedWindow.Mainapp.Data.Raw,1)
                    texttoshow{i+1} = num2str(i);
                end
                app.ChannelSelection(2) = size(app.EventRelatedWindow.Mainapp.Data.Raw,1);
            else
                for i = 1:size(app.EventRelatedWindow.Mainapp.Data.Preprocessed,1)
                    texttoshow{i+1} = num2str(i);
                end
                app.ChannelSelection(2) = size(app.EventRelatedWindow.Mainapp.Data.Preprocessed,1);
            end

            app.SurfaceChannelDropDown_2.Items = texttoshow;

            texttoshow = ['1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))];
            app.EventNumberSelectionEditField.Value = texttoshow;

            %% Extract GUI Infos
            spaceindicie = strfind(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange," ");
            app.TimearoundEvent(1) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie(1)-1));
            app.TimearoundEvent(2) = str2double(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie(1)+1:end));

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));
            
            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;

            CSD.HammWindow = str2double(app.HammWindowEditField.Value);

            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataType,"Preprocessed") && isfield(app.EventRelatedWindow.Mainapp.Data.Info,'DownsampledSampleRate')
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.DownsampledSampleRate:app.TimearoundEvent(2);
            else
                app.EventTime = 0-app.TimearoundEvent(1):1/app.EventRelatedWindow.Mainapp.Data.Info.NativeSamplingRate:app.TimearoundEvent(2);
            end
            
            %% Plot CSD
            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            end
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: EventNumberSelectionEditField
        function EventNumberSelectionEditFieldValueChanged(app, event)
            %% Check Proper Inputs

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data')
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2))),1,0);
            else
                [app.EventNumberSelectionEditField.Value] = Utility_SimpleCheckInputs(app.EventNumberSelectionEditField.Value,"Two",strcat('1,',num2str(size(app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData,2))),1,0);
            end
            
            value = app.EventNumberSelectionEditField.Value;
            CommaIndex = strfind(value,",");
            EventNr(1) = str2double(value(1:CommaIndex-1));
            EventNr(2) = str2double(value(CommaIndex+1:end));

            if EventNr(2) > size(app.EventRelatedWindow.Mainapp.Data.EventRelatedData,2)
                msgbox("More events selected than available for this event channel. Setting second limit to max event number");
                app.EventNumberSelectionEditField.Value = strcat(num2str(EventNr(1)),",",num2str(length(app.EventRelatedWindow.Mainapp.Data.Events{str2double(app.EventChannelSelectionDropDown.Value)})));
            end

            %% Extract Values to do calculations

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            end
        end

        % Value changed function: HammWindowEditField
        function HammWindowEditFieldValueChanged(app, event)
            %% Check Proper Inputs
            [app.HammWindowEditField.Value] = Utility_SimpleCheckInputs(app.HammWindowEditField.Value,"One",'5',0,0);

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            end
        end

        % Value changed function: SurfaceChannelDropDown_2
        function SurfaceChannelDropDown_2ValueChanged(app, event)
            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            end
        end

        % Value changed function: ClimminmaxEditField
        function ClimminmaxEditFieldValueChanged(app, event)
            [app.ClimminmaxEditField.Value] = Utility_SimpleCheckInputs(app.ClimminmaxEditField.Value,"Two",strcat(''),0,1);

            ErrorCLIM = 0;
            if ~isempty(app.ClimminmaxEditField.Value)
                
                if ErrorCLIM == 0
                    value = app.ClimminmaxEditField.Value;
                    indicesep = find(value == ',');
                    climcostumERP(1,1) = str2double(value(1:indicesep-1));
                    climcostumERP(1,2) = str2double(value(indicesep+1:end));
        
                    app.UIAxes.CLim = climcostumERP;
                end
            end
            
        end

        % Button pushed function: AutoClimButton
        function AutoClimButtonPushed(app, event)
            if ~isempty(app.climCSD)
                app.UIAxes.CLim = app.climCSD;
                app.ClimminmaxEditField.Value = "";
            end
        end

        % Value changed function: DataTypeDropDown
        function DataTypeDropDownValueChanged(app, event)
            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            end
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.CSDAnalysisUIFigure,fullfile(path,file))
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.CSDAnalysisUIFigure,fullfile(path,file))
        end

        % Menu selected function: ChangeTitleMenu
        function ChangeTitleMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'title');
        end

        % Menu selected function: ChangeXLabelMenu
        function ChangeXLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlabel');
        end

        % Menu selected function: ChangeYLabelMenu
        function ChangeYLabelMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylabel');
        end

        % Menu selected function: ChangeXlimMenu
        function ChangeXlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'xlim');
        end

        % Menu selected function: ChangeYlimMenu
        function ChangeYlimMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'ylim');
        end

        % Menu selected function: ChangeLegendMenu
        function ChangeLegendMenuSelected(app, event)
            Figure_Property_Change_Window(app.UIAxes,'legend');
        end

        % Menu selected function: ChangeFontsizeMenu
        function ChangeFontsizeMenuSelected(app, event)
            
        end

        % Menu selected function: Show2DimensionalPlotsMenu
        function Show2DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.TwoORThreeD = "TwoD";

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            end

        end

        % Menu selected function: matMenu
        function matMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".mat",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Current Source Density Analysis");
        end

        % Menu selected function: txtMenu
        function txtMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".txt",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Current Source Density Analysis");
        end

        % Menu selected function: csvMenu
        function csvMenuSelected(app, event)
            TimeRangePlot = app.EventRelatedWindow.Mainapp.Data.Info.EventRelatedDataTimeRange(end);
         
            StartTime = 0;

            Utility_Get_Plot_Data(app.EventRelatedWindow.Mainapp.CurrentPlotData,app.EventRelatedWindow.Mainapp.Data,".csv",app.EventRelatedWindow.Mainapp.executableFolder,TimeRangePlot,StartTime,"Current Source Density Analysis");
        end

        % Menu selected function: Show3DimensionalPlotsMenu
        function Show3DimensionalPlotsMenuSelected(app, event)
            app.UIAxes.NextPlot = "replace";
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add";

            app.TwoORThreeD = "ThreeD";

            commaindicie = strfind(app.EventNumberSelectionEditField.Value,",");
            EventNr(1) = str2double(app.EventNumberSelectionEditField.Value(1:commaindicie-1));
            EventNr(2) = str2double(app.EventNumberSelectionEditField.Value(commaindicie+1:end));

            CSD.ChannelSpacing = app.EventRelatedWindow.Mainapp.Data.Info.ChannelSpacing;
            CSD.HammWindow = str2double(app.HammWindowEditField.Value);
            if strcmp(app.SurfaceChannelDropDown_2.Value,"Non")
                CSD.SurfaceChannel = 1;
            else
                CSD.SurfaceChannel = str2double(app.SurfaceChannelDropDown_2.Value);
            end

            if strcmp(app.DataTypeDropDown.Value,'Raw Event Related Data') 
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.EventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            else
                [app.climCSD,~,~,~,app.EventRelatedWindow.Mainapp.CurrentPlotData] = Event_Module_Compute_and_Plot_ERP_CSD(app.EventRelatedWindow.Mainapp.Data,app.UIAxes,[],app.EventRelatedWindow.Mainapp.Data.PreprocessedEventRelatedData(:,EventNr(1):EventNr(2),:),app.EventTime,app.ChannelSelection,CSD,[],[],[],app.TwoORThreeD,app.EventRelatedWindow.Mainapp.CurrentPlotData);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CSDAnalysisUIFigure = uifigure('Visible', 'off');
            app.CSDAnalysisUIFigure.Name = 'Event CSD Window';
            app.CSDAnalysisUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.CSDAnalysisUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofWindowMenu.Text = 'Save Snapshot of Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.PlotMenu = uimenu(app.CSDAnalysisUIFigure);
            app.PlotMenu.Text = '   Plot   ';
            app.Show2DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show2DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show2DimensionalPlotsMenuSelected, true);
            app.Show2DimensionalPlotsMenu.Text = 'Show 2 Dimensional Plots';
            app.Show3DimensionalPlotsMenu = uimenu(app.PlotMenu);
            app.Show3DimensionalPlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @Show3DimensionalPlotsMenuSelected, true);
            app.Show3DimensionalPlotsMenu.Text = 'Show 3 Dimensional Plots';
            app.ExportDataasMenu = uimenu(app.CSDAnalysisUIFigure);
            app.ExportDataasMenu.Text = '   Export Data as   ';
            app.matMenu = uimenu(app.ExportDataasMenu);
            app.matMenu.MenuSelectedFcn = createCallbackFcn(app, @matMenuSelected, true);
            app.matMenu.Text = '.mat';
            app.txtMenu = uimenu(app.ExportDataasMenu);
            app.txtMenu.MenuSelectedFcn = createCallbackFcn(app, @txtMenuSelected, true);
            app.txtMenu.Text = '.txt';
            app.csvMenu = uimenu(app.ExportDataasMenu);
            app.csvMenu.MenuSelectedFcn = createCallbackFcn(app, @csvMenuSelected, true);
            app.csvMenu.Text = '.csv';
            app.UIAxes = uiaxes(app.CSDAnalysisUIFigure);
            app.CurrentSourceDensityWindowLabel = uilabel(app.CSDAnalysisUIFigure);
            app.CurrentSourceDensityWindowLabel.Text = 'Current Source Density Window';
            app.Image = uiimage(app.CSDAnalysisUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.CSDAnalysisUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.CSDAnalysisUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CSDParameterPanel = uipanel(app.CSDAnalysisUIFigure);
            app.CSDParameterPanel.Title = 'CSD Parameter';
            app.HammWindowEditField = uieditfield(app.CSDParameterPanel, 'text');
            app.HammWindowEditField.ValueChangedFcn = createCallbackFcn(app, @HammWindowEditFieldValueChanged, true);
            app.HammWindowEditField.Value = '5';
            app.HammWindowEditFieldLabel = uilabel(app.CSDParameterPanel);
            app.HammWindowEditFieldLabel.Text = 'Hamm Window';
            app.SurfaceChannelDropDown_2Label = uilabel(app.CSDParameterPanel);
            app.SurfaceChannelDropDown_2Label.Text = 'Surface Channel';
            app.SurfaceChannelDropDown_2 = uidropdown(app.CSDParameterPanel);
            app.SurfaceChannelDropDown_2.Items = {'Non'};
            app.SurfaceChannelDropDown_2.ValueChangedFcn = createCallbackFcn(app, @SurfaceChannelDropDown_2ValueChanged, true);
            app.SurfaceChannelDropDown_2.Value = 'Non';
            app.ClimminmaxEditFieldLabel = uilabel(app.CSDParameterPanel);
            app.ClimminmaxEditFieldLabel.Text = 'Clim [min max]';
            app.ClimminmaxEditField = uieditfield(app.CSDParameterPanel, 'text');
            app.ClimminmaxEditField.ValueChangedFcn = createCallbackFcn(app, @ClimminmaxEditFieldValueChanged, true);
            app.EventNumberSelectionEditFieldLabel = uilabel(app.CSDParameterPanel);
            app.EventNumberSelectionEditFieldLabel.Text = {'Event Number'; 'Selection'};
            app.EventNumberSelectionEditField = uieditfield(app.CSDParameterPanel, 'text');
            app.EventNumberSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @EventNumberSelectionEditFieldValueChanged, true);
            app.DataTypeDropDownLabel = uilabel(app.CSDParameterPanel);
            app.DataTypeDropDownLabel.Text = 'Data Type';
            app.DataTypeDropDown = uidropdown(app.CSDParameterPanel);
            app.DataTypeDropDown.Items = {};
            app.DataTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @DataTypeDropDownValueChanged, true);
            app.DataTypeDropDown.Value = {};
            app.AutoClimButton = uibutton(app.CSDParameterPanel, 'push');
            app.AutoClimButton.ButtonPushedFcn = createCallbackFcn(app, @AutoClimButtonPushed, true);
            app.AutoClimButton.Text = 'Auto Clim';
            app.ContextMenu3 = uicontextmenu(app.CSDAnalysisUIFigure);
            app.ChangeTitleMenu = uimenu(app.ContextMenu3);
            app.ChangeTitleMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTitleMenuSelected, true);
            app.ChangeTitleMenu.Text = 'Change Title';
            app.ChangeXLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeXLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXLabelMenuSelected, true);
            app.ChangeXLabelMenu.Text = 'Change X Label';
            app.ChangeYLabelMenu = uimenu(app.ContextMenu3);
            app.ChangeYLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYLabelMenuSelected, true);
            app.ChangeYLabelMenu.Text = 'Change Y Label';
            app.ChangeXlimMenu = uimenu(app.ContextMenu3);
            app.ChangeXlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeXlimMenuSelected, true);
            app.ChangeXlimMenu.Text = 'Change Xlim';
            app.ChangeYlimMenu = uimenu(app.ContextMenu3);
            app.ChangeYlimMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeYlimMenuSelected, true);
            app.ChangeYlimMenu.Text = 'Change Ylim';
            app.ChangeLegendMenu = uimenu(app.ContextMenu3);
            app.ChangeLegendMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeLegendMenuSelected, true);
            app.ChangeLegendMenu.Text = 'Change Legend';
            app.ChangeFontsizeMenu = uimenu(app.ContextMenu3);
            app.ChangeFontsizeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeFontsizeMenuSelected, true);
            app.ChangeFontsizeMenu.Text = 'Change Fontsize';
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.CSDAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CSD_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.CSDAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.CSDAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CSDAnalysisUIFigure)
        end
    end
end