classdef Preprocessing_Events_Trial_Rejection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TrialRejectionWindowUIFigure  matlab.ui.Figure
        CloseButton                   matlab.ui.control.Button
        SaveasnewDatasetButton        matlab.ui.control.Button
        NeuromodToolboxLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        TrialRejectionWindowLabel     matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        ClimfromtoEditField           matlab.ui.control.EditField
        ClimfromtoEditFieldLabel      matlab.ui.control.Label
        RejectTrialsfromtoEditField   matlab.ui.control.EditField
        RejectTrialsfromtoEditFieldLabel  matlab.ui.control.Label
        ChannelofInterestDropDown     matlab.ui.control.DropDown
        ChannelofInteresjustforplottLabel  matlab.ui.control.Label
        UIAxes_2                      matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    %% All of the following code in this section is to display infos when clicking on plots

    properties (Access = public)
        % Add a property to store the names of the vectors
        TextHandle = []; % Store the handle to the displayed text
        TrialsTextHandle = []; % Store the handle to the displayed text
        Mainapp = [];
        EventTime = [];
        EventsRelatedApp = [];
    end
        
    
    methods (Access = private)
      
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp, EventsRelatedApp)
            movegui(app.TrialRejectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;
            app.EventsRelatedApp = EventsRelatedApp;

            %% Fill GUI fields
            texttoshow = {};
            for nchannel = 1:size(app.Mainapp.Data.EventRelatedData,1)
                texttoshow{nchannel} = num2str(nchannel);
            end

            app.ChannelofInterestDropDown.Items = texttoshow;

            EventRelatedDataTimeRange = [];
                 
            spaceindicie = find(app.Mainapp.Data.Info.EventRelatedDataTimeRange == ' ');
            EventRelatedDataTimeRange(1) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(1:spaceindicie));
            EventRelatedDataTimeRange(2) = str2double(app.Mainapp.Data.Info.EventRelatedDataTimeRange(spaceindicie+1:end));

            if strcmp(app.Mainapp.Data.Info.EventRelatedDataType,'Raw')
                app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
            else
                if isfield(app.Mainapp.Data.Info,"DownsampleFactor")
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.DownsampledSampleRate:EventRelatedDataTimeRange(2);
                else
                    app.EventTime = 0-EventRelatedDataTimeRange(1):1/app.Mainapp.Data.Info.NativeSamplingRate:EventRelatedDataTimeRange(2);
                end
            end

            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
            else
                [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
            end

            if Error == 1
                app.RejectTrialsfromtoEditField.Value = "";     
            end
        end

        % Button pushed function: SaveasnewDatasetButton
        function SaveasnewDatasetButtonPushed(app, event)
            if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                [app.Mainapp.Data.PreprocessedEventRelatedData,Error] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'OnlyReject',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
            else
                [app.Mainapp.Data.PreprocessedEventRelatedData,Error] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,'OnlyReject',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
            end

            CommaTest = find(app.RejectTrialsfromtoEditField.Value == ',');
            Trials(1,1) = str2double(app.RejectTrialsfromtoEditField.Value(1:CommaTest(1)-1));
            Trials(1,2) = str2double(app.RejectTrialsfromtoEditField.Value(CommaTest+1:end));

            Channel = [1,size(app.Mainapp.Data.PreprocessedEventRelatedData,1)];

            [app.Mainapp.Data] = Preprocessing_Events_Add_Preprocessing_Info(app.Mainapp.Data,'Trial Rejection',Channel,Trials,[]);

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Preprocessing_Events_PopulateInfoText(app.EventsRelatedApp.TextArea,app.Mainapp.Data);

            msgbox("Rejection of selected trials succesfull.");
         
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: ChannelofInterestDropDown
        function ChannelofInterestDropDownValueChanged(app, event)
        
             if ~isempty(app.RejectTrialsfromtoEditField.Value)
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
                else
                    [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
                end

                if Error == 1
                    app.RejectTrialsfromtoEditField.Value = "";     
                end
            else
                %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
                else
                    [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
                end

                if Error == 1
                    app.RejectTrialsfromtoEditField.Value = "";     
                end
            end
        end

        % Value changed function: ClimfromtoEditField
        function ClimfromtoEditFieldValueChanged(app, event)
            value = app.ClimfromtoEditField.Value;
            
            CommaTest = find(value == ',');
            Inputvalue(1,1) = str2double(value(1:CommaTest(1)-1));
            Inputvalue(1,2) = str2double(value(CommaTest+1:end));

            app.UIAxes.CLim = [Inputvalue(1),Inputvalue(2)];
        end

        % Value changed function: RejectTrialsfromtoEditField
        function RejectTrialsfromtoEditFieldValueChanged(app, event)
            value = app.RejectTrialsfromtoEditField.Value;
            
            if ~isempty(value)
            %% Get Data based on Input
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
                else
                    [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,'RejectandPlot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
                end

                if Error == 1
                    app.RejectTrialsfromtoEditField.Value = "";     
                end

            else
                if isfield(app.Mainapp.Data,'PreprocessedEventRelatedData')
                    [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.PreprocessedEventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
                else
                    [~,Error,app.RejectTrialsfromtoEditField.Value] = Preprocessing_Events_Plot_and_Apply_Trial_Rejection(app.Mainapp.Data.EventRelatedData,app.EventTime,'Plot',app.UIAxes,app.UIAxes_2,app.ChannelofInterestDropDown.Value,app.RejectTrialsfromtoEditField.Value);
                end

                if Error == 1
                    app.RejectTrialsfromtoEditField.Value = "";     
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TrialRejectionWindowUIFigure = uifigure('Visible', 'off');
            app.TrialRejectionWindowUIFigure.Name = 'Trial Rejection Window';
            app.TrialRejectionWindowUIFigure.Icon = 'Logo.png';
            app.UIAxes = uiaxes(app.TrialRejectionWindowUIFigure);
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.TrialRejectionWindowUIFigure);
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Panel = uipanel(app.TrialRejectionWindowUIFigure);
            app.ChannelofInteresjustforplottLabel = uilabel(app.Panel);
            app.ChannelofInteresjustforplottLabel.Text = {'Channel of Interest'; '(just for plot!)'};
            app.ChannelofInterestDropDown = uidropdown(app.Panel);
            app.ChannelofInterestDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15', '16'};
            app.ChannelofInterestDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelofInterestDropDownValueChanged, true);
            app.ChannelofInterestDropDown.Value = '1';
            app.RejectTrialsfromtoEditFieldLabel = uilabel(app.Panel);
            app.RejectTrialsfromtoEditFieldLabel.Text = {'Reject Trials:'; '(from,to)'};
            app.RejectTrialsfromtoEditField = uieditfield(app.Panel, 'text');
            app.RejectTrialsfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @RejectTrialsfromtoEditFieldValueChanged, true);
            app.ClimfromtoEditFieldLabel = uilabel(app.Panel);
            app.ClimfromtoEditFieldLabel.Text = {'Clim'; '[from , to]'};
            app.ClimfromtoEditField = uieditfield(app.Panel, 'text');
            app.ClimfromtoEditField.ValueChangedFcn = createCallbackFcn(app, @ClimfromtoEditFieldValueChanged, true);
            app.TrialRejectionWindowLabel = uilabel(app.TrialRejectionWindowUIFigure);
            app.TrialRejectionWindowLabel.Text = 'Trial Rejection Window';
            app.Image = uiimage(app.TrialRejectionWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.TrialRejectionWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.SaveasnewDatasetButton = uibutton(app.TrialRejectionWindowUIFigure, 'push');
            app.SaveasnewDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @SaveasnewDatasetButtonPushed, true);
            app.SaveasnewDatasetButton.Text = {'Save as '; 'new Dataset'};
            app.CloseButton = uibutton(app.TrialRejectionWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.TrialRejectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Preprocessing_Events_Trial_Rejection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.TrialRejectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.TrialRejectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TrialRejectionWindowUIFigure)
        end
    end
end