classdef Spike_Detection_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeDetectionWindowUIFigure  matlab.ui.Figure
        TextArea                      matlab.ui.control.TextArea
        CloseButton                   matlab.ui.control.Button
        StartSpikeDetectionButton     matlab.ui.control.Button
        NeuromodToolboxLabel          matlab.ui.control.Label
        Image                         matlab.ui.control.Image
        SpikeDetectionWindowLabel     matlab.ui.control.Label
        DetectionParameterPanel       matlab.ui.container.Panel
        MinDepthofArtefactmEditField  matlab.ui.control.EditField
        MinDepthofArtefactmEditFieldLabel  matlab.ui.control.Label
        VerticalSpikeOffsetToleranceSamplesEditField  matlab.ui.control.EditField
        VerticalSpikeOffsetToleranceSamplesEditFieldLabel  matlab.ui.control.Label
        FilterVerticalSpikeArtefactsCheckBox  matlab.ui.control.CheckBox
        MeanDropDown                  matlab.ui.control.DropDown
        MeanDropDownLabel             matlab.ui.control.Label
        ThresholdEditField            matlab.ui.control.EditField
        ThresholdEditFieldLabel       matlab.ui.control.Label
        DetectionMethodDropDown       matlab.ui.control.DropDown
        DetectionMethodDropDownLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeDetectionWindowUIFigure, 'center');

            app.Mainapp = Mainapp;

            %% Check if high pass filtered data is available. If not, display error message and return
            if ~isfield(app.Mainapp.Data,'Preprocessed')
                f = msgbox("Error: No preprocessed Data detected. Spike Detection on Raw Data wont work! High pass filter and open this window again!");
                delete(app);
                return;
            else
                if ~isfield(app.Mainapp.Data.Info,'FilterMethod')
                    f = msgbox("Error: No high pass filtered Data detected. Spike Detection on Raw Data wont work! High pass filter and open this window again!");
                    delete(app);
                    return;
                else
                    if ~strcmp(app.Mainapp.Data.Info.FilterMethod,"High-Pass")
                        f = msgbox("Error: No high pass filtered Data detected. Spike Detection on Raw Data wont work! High pass filter and open this window again!");
                        delete(app);
                        return;
                    end
                end
            end

            app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
            app.MinDepthofArtefactmEditField.Enable = "off";

            app.TextArea.Value = "Ready. 'Filter Vertical Spike Artefacts' is deleting spike times occuring along more than a certain depth (Depth of Artefact) at the same time point (samples) +/- a tolerance (vertical spike offset)";

        end

        % Button pushed function: StartSpikeDetectionButton
        function StartSpikeDetectionButtonPushed(app, event)
            app.TextArea.Value = strcat("Extracting Spikes. Please wait until the progress bar closes");

            %% Spike Detection
            [app.Mainapp.Data,~] = Spike_Module_Spike_Detection(app.Mainapp.Data,app.DetectionMethodDropDown.Value,app.MeanDropDown.Value,str2double(app.ThresholdEditField.Value),app.FilterVerticalSpikeArtefactsCheckBox.Value,app.VerticalSpikeOffsetToleranceSamplesEditField.Value,app.MinDepthofArtefactmEditField.Value);

            %% Aftmeramth: Resetting Main window Plots and plot data again
            app.Mainapp.SpikeDataCheckBox_2.Enable = "on";
            app.Mainapp.SpikeDataCheckBox_2.Value = 0;
            app.TextArea.Value = strcat("Spike detection finished. They can now be visualized and analyzed in the main window");

            app.Mainapp.UIAxes.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes,0,0);
            app.Mainapp.UIAxes_2.NextPlot = "replace"; 
            plot(app.Mainapp.UIAxes_2,0,0);
            app.Mainapp.SpikeDataCheckBox_2.Enable = "on";

            if app.Mainapp.SpikeDataCheckBox_2.Value == 1
                app.Mainapp.Plotspikes = "Spikes";
            else
                app.Mainapp.Plotspikes = "No";
            end

            Utility_Show_Info_Loaded_Data(app.Mainapp);

            Organize_Prepare_Plot_and_Extract_GUI_Info(app.Mainapp,1,"Initial","Static",app.Mainapp.PlotEvents,app.Mainapp.Plotspikes);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: DetectionMethodDropDown
        function DetectionMethodDropDownValueChanged(app, event)
            
        end

        % Value changed function: FilterVerticalSpikeArtefactsCheckBox
        function FilterVerticalSpikeArtefactsCheckBoxValueChanged(app, event)
            value = app.FilterVerticalSpikeArtefactsCheckBox.Value;
            if value == 1
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "on";
                app.MinDepthofArtefactmEditField.Enable = "on";
            else
                app.VerticalSpikeOffsetToleranceSamplesEditField.Enable = "off";
                app.MinDepthofArtefactmEditField.Enable = "off";
            end
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            [app.ThresholdEditField.Value] = Utility_SimpleCheckInputs(app.ThresholdEditField.Value,"One",'4',0,0);
            
        end

        % Value changed function: 
        % VerticalSpikeOffsetToleranceSamplesEditField
        function VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged(app, event)
            [app.VerticalSpikeOffsetToleranceSamplesEditField.Value] = Utility_SimpleCheckInputs(app.VerticalSpikeOffsetToleranceSamplesEditField.Value,"One",'3',0,0);
            
        end

        % Value changed function: MinDepthofArtefactmEditField
        function MinDepthofArtefactmEditFieldValueChanged(app, event)
            [app.MinDepthofArtefactmEditField.Value] = Utility_SimpleCheckInputs(app.MinDepthofArtefactmEditField.Value,"One",'200',0,0);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeDetectionWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeDetectionWindowUIFigure.Name = 'Spike Detection Window';
            app.SpikeDetectionWindowUIFigure.Icon = 'Logo.png';
            app.DetectionParameterPanel = uipanel(app.SpikeDetectionWindowUIFigure);
            app.DetectionParameterPanel.Title = 'Detection Parameter';
            app.DetectionMethodDropDownLabel = uilabel(app.DetectionParameterPanel);
            app.DetectionMethodDropDownLabel.Text = 'Detection Method';
            app.DetectionMethodDropDown = uidropdown(app.DetectionParameterPanel);
            app.DetectionMethodDropDown.Items = {'Quiroga Method', 'Threshold: Mean - Std', 'Threshold: Median - Std'};
            app.DetectionMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @DetectionMethodDropDownValueChanged, true);
            app.DetectionMethodDropDown.Value = 'Quiroga Method';
            app.ThresholdEditFieldLabel = uilabel(app.DetectionParameterPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.DetectionParameterPanel, 'text');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = '4';
            app.MeanDropDownLabel = uilabel(app.DetectionParameterPanel);
            app.MeanDropDownLabel.Text = 'Mean';
            app.MeanDropDown = uidropdown(app.DetectionParameterPanel);
            app.MeanDropDown.Items = {'All Channel', 'Individual Ch.'};
            app.MeanDropDown.Value = 'Individual Ch.';
            app.FilterVerticalSpikeArtefactsCheckBox = uicheckbox(app.DetectionParameterPanel);
            app.FilterVerticalSpikeArtefactsCheckBox.ValueChangedFcn = createCallbackFcn(app, @FilterVerticalSpikeArtefactsCheckBoxValueChanged, true);
            app.FilterVerticalSpikeArtefactsCheckBox.Text = '   Filter Vertical Spike Artefacts';
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel = uilabel(app.DetectionParameterPanel);
            app.VerticalSpikeOffsetToleranceSamplesEditFieldLabel.Text = {'Vertical Spike Offset'; 'Tolerance [Samples]'};
            app.VerticalSpikeOffsetToleranceSamplesEditField = uieditfield(app.DetectionParameterPanel, 'text');
            app.VerticalSpikeOffsetToleranceSamplesEditField.ValueChangedFcn = createCallbackFcn(app, @VerticalSpikeOffsetToleranceSamplesEditFieldValueChanged, true);
            app.VerticalSpikeOffsetToleranceSamplesEditField.Value = '3';
            app.MinDepthofArtefactmEditFieldLabel = uilabel(app.DetectionParameterPanel);
            app.MinDepthofArtefactmEditFieldLabel.Text = {'Min. Depth of Artefact'; '[µm]'};
            app.MinDepthofArtefactmEditField = uieditfield(app.DetectionParameterPanel, 'text');
            app.MinDepthofArtefactmEditField.ValueChangedFcn = createCallbackFcn(app, @MinDepthofArtefactmEditFieldValueChanged, true);
            app.MinDepthofArtefactmEditField.Value = '200';
            app.SpikeDetectionWindowLabel = uilabel(app.SpikeDetectionWindowUIFigure);
            app.SpikeDetectionWindowLabel.Text = 'Spike Detection Window';
            app.Image = uiimage(app.SpikeDetectionWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SpikeDetectionWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.StartSpikeDetectionButton = uibutton(app.SpikeDetectionWindowUIFigure, 'push');
            app.StartSpikeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @StartSpikeDetectionButtonPushed, true);
            app.StartSpikeDetectionButton.Text = {'Start Spike '; 'Detection'};
            app.CloseButton = uibutton(app.SpikeDetectionWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.TextArea = uitextarea(app.SpikeDetectionWindowUIFigure);
            % Show the figure after all components are created
            app.SpikeDetectionWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Spike_Detection_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeDetectionWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeDetectionWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeDetectionWindowUIFigure)
        end
    end
end