classdef Continous_Waveform_Analysis_Window < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SpikeWaveformWindowUIFigure     matlab.ui.Figure
        SnapshotMenu                    matlab.ui.container.Menu
        SaveSnapshotMenu                matlab.ui.container.Menu
        PNGMenu                         matlab.ui.container.Menu
        JPGMenu                         matlab.ui.container.Menu
        Menu                            matlab.ui.container.Menu
        Panel_4                         matlab.ui.container.Panel
        UnitstoShow123EditField         matlab.ui.control.EditField
        UnitstoShow123EditFieldLabel    matlab.ui.control.Label
        NumWaveformsEditField           matlab.ui.control.EditField
        NumWaveformsEditFieldLabel      matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        UnitstoShow123EditField_2       matlab.ui.control.EditField
        UnitstoShow123EditField_2Label  matlab.ui.control.Label
        NumWaveformsEditField_2         matlab.ui.control.EditField
        NumWaveformsEditField_2Label    matlab.ui.control.Label
        CloseButton                     matlab.ui.control.Button
        NeuromodToolboxLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        KilosortAnalysisWindowLabel     matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        MaxISItoshowsEditField          matlab.ui.control.EditField
        MaxISItoshowsEditFieldLabel     matlab.ui.control.Label
        BinNumberISIEditField           matlab.ui.control.EditField
        BinNumberISIEditFieldLabel      matlab.ui.control.Label
        Panel_2                         matlab.ui.container.Panel
        UnitstoShow123EditField_3       matlab.ui.control.EditField
        UnitstoShow123EditField_3Label  matlab.ui.control.Label
        NumWaveformsEditField_3         matlab.ui.control.EditField
        NumWaveformsEditField_3Label    matlab.ui.control.Label
        Panel_5                         matlab.ui.container.Panel
        DeleteUnitDropDown              matlab.ui.control.DropDown
        DeleteUnitDropDownLabel         matlab.ui.control.Label
        DeleteButton                    matlab.ui.control.Button
        UIAxes_9                        matlab.ui.control.UIAxes
        UIAxes_8                        matlab.ui.control.UIAxes
        UIAxes_7                        matlab.ui.control.UIAxes
        UIAxes_6                        matlab.ui.control.UIAxes
        UIAxes_5                        matlab.ui.control.UIAxes
        UIAxes_4                        matlab.ui.control.UIAxes
        UIAxes_3                        matlab.ui.control.UIAxes
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes_1                        matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Mainapp = []; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Mainapp)
            movegui(app.SpikeWaveformWindowUIFigure, 'center');
            
            app.Mainapp = Mainapp;
            
            app.NumWaveformsEditField.Value = "20";
            app.NumWaveformsEditField_2.Value = "20";
            app.NumWaveformsEditField_3.Value = "20";

            app.UnitstoShow123EditField.Value = "1,2";
            app.UnitstoShow123EditField_2.Value = "3,4";
            app.UnitstoShow123EditField_3.Value = "4,5";
            
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(1:commaindex(1)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(1:commaindex(1)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(end)+1:end));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(end)+1:end));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(end)+1:end));
                else
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{1} = str2double(app.NumWaveformsEditField.Value);
            Waves{2} = str2double(app.NumWaveformsEditField_2.Value);
            Waves{3} = str2double(app.NumWaveformsEditField_3.Value);
            
            figs.UIAxes_1 = app.UIAxes_1;
            figs.UIAxes_2 = app.UIAxes_2;
            figs.UIAxes_3 = app.UIAxes_3;

            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,Waves,figs);

            figs.UIAxes_1 = app.UIAxes_4;
            figs.UIAxes_2 = app.UIAxes_5;
            figs.UIAxes_3 = app.UIAxes_6;

            [ISIs] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,Units,Waves,figs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      

            figs.UIAxes_1 = app.UIAxes_7;
            figs.UIAxes_2 = app.UIAxes_8;
            figs.UIAxes_3 = app.UIAxes_9;

            Spikes_Module_AutoCorrelogram(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,figs,Units);

        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end

        % Value changed function: UnitstoShow123EditField
        function UnitstoShow123EditFieldValueChanged(app, event)
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(end)+1:end));
                else
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{1} = str2double(app.NumWaveformsEditField.Value);
            
            figs.UIAxes_1 = app.UIAxes_1;

            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,Waves,figs)

            figs.UIAxes_1 = app.UIAxes_4;
            figs.UIAxes_2 = app.UIAxes_5;
            figs.UIAxes_3 = app.UIAxes_6;

            [ISIs] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,Units,Waves,figs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
        end

        % Value changed function: NumWaveformsEditField
        function NumWaveformsEditFieldValueChanged(app, event)
            %% Prepare inputs

            commaindex = find(app.UnitstoShow123EditField.Value==',');
            
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(end)+1:end));
                else
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
            end

            Waves{1} = str2double(app.NumWaveformsEditField.Value);
            
            figs.UIAxes_1 = app.UIAxes_1;


            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,Waves,figs)

            figs.UIAxes_1 = app.UIAxes_4;
            figs.UIAxes_2 = app.UIAxes_5;
            figs.UIAxes_3 = app.UIAxes_6;

            [ISIs] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,Units,Waves,figs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
        end

        % Value changed function: NumWaveformsEditField_2
        function NumWaveformsEditField_2ValueChanged(app, event)
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField_2.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(end)+1:end));
                else
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{2} = str2double(app.NumWaveformsEditField_2.Value);
            
            figs.UIAxes_1 = app.UIAxes_1;
            figs.UIAxes_2 = app.UIAxes_2;

            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,Waves,figs)

            figs.UIAxes_1 = app.UIAxes_4;
            figs.UIAxes_2 = app.UIAxes_5;
            figs.UIAxes_3 = app.UIAxes_6;

            [ISIs] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,Units,Waves,figs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
        end

        % Value changed function: UnitstoShow123EditField_2
        function UnitstoShow123EditField_2ValueChanged(app, event)
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField_2.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(end)+1:end));
                else
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{2} = str2double(app.NumWaveformsEditField_2.Value);
            
            figs.UIAxes_1 = app.UIAxes_1;
            figs.UIAxes_2 = app.UIAxes_2;

            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,Waves,figs)

            figs.UIAxes_1 = app.UIAxes_4;
            figs.UIAxes_2 = app.UIAxes_5;
            figs.UIAxes_3 = app.UIAxes_6;

            [ISIs] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,Units,Waves,figs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
            
        end

        % Value changed function: UnitstoShow123EditField_3
        function UnitstoShow123EditField_3ValueChanged(app, event)
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField_3.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(end)+1:end));
                else
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{3} = str2double(app.NumWaveformsEditField_3.Value);
            
            figs.UIAxes_1 = app.UIAxes_1;
            figs.UIAxes_2 = app.UIAxes_2;
            figs.UIAxes_3 = app.UIAxes_3;

            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,Waves,figs)

            figs.UIAxes_1 = app.UIAxes_4;
            figs.UIAxes_2 = app.UIAxes_5;
            figs.UIAxes_3 = app.UIAxes_6;

            [ISIs] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,Units,Waves,figs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
        end

        % Value changed function: NumWaveformsEditField_3
        function NumWaveformsEditField_3ValueChanged(app, event)
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField_3.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(end)+1:end));
                else
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{3} = str2double(app.NumWaveformsEditField_3.Value);
            
            figs.UIAxes_1 = app.UIAxes_1;
            figs.UIAxes_2 = app.UIAxes_2;
            figs.UIAxes_3 = app.UIAxes_3;

            Spike_Waveforms_Plot_Waveforms(app.Mainapp.Data,Units,Waves,figs)

            figs.UIAxes_1 = app.UIAxes_4;
            figs.UIAxes_2 = app.UIAxes_5;
            figs.UIAxes_3 = app.UIAxes_6;

            [ISIs] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,Units,Waves,figs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.SpikeWaveformWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.SpikeWaveformWindowUIFigure,fullfile(path,file)) 
        end

        % Value changed function: MaxISItoshowsEditField
        function MaxISItoshowsEditFieldValueChanged(app, event)
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(1:commaindex(1)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(1:commaindex(1)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(end)+1:end));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(end)+1:end));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(end)+1:end));
                else
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{1} = str2double(app.NumWaveformsEditField.Value);
            Waves{2} = str2double(app.NumWaveformsEditField_2.Value);
            Waves{3} = str2double(app.NumWaveformsEditField_3.Value);
            
            figs.UIAxes_1 = app.UIAxes_4;
            figs.UIAxes_2 = app.UIAxes_5;
            figs.UIAxes_3 = app.UIAxes_6;

            [ISIs] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,Units,Waves,figs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
            
        end

        % Value changed function: BinNumberISIEditField
        function BinNumberISIEditFieldValueChanged(app, event)
            %% Prepare inputs
            commaindex = find(app.UnitstoShow123EditField.Value==',');
            for i = 1:length(commaindex)+1
                if i == 1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(1:commaindex(1)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(1:commaindex(1)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(1:commaindex(1)-1));
                elseif i == length(commaindex)+1
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(end)+1:end));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(end)+1:end));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(end)+1:end));
                else
                    Units{1}(i) = str2double(app.UnitstoShow123EditField.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{2}(i) = str2double(app.UnitstoShow123EditField_2.Value(commaindex(i-1)+1:commaindex(i)-1));
                    Units{3}(i) = str2double(app.UnitstoShow123EditField_3.Value(commaindex(i-1)+1:commaindex(i)-1));
                end
                
            end

            Waves{1} = str2double(app.NumWaveformsEditField.Value);
            Waves{2} = str2double(app.NumWaveformsEditField_2.Value);
            Waves{3} = str2double(app.NumWaveformsEditField_3.Value);
            
            figs.UIAxes_1 = app.UIAxes_4;
            figs.UIAxes_2 = app.UIAxes_5;
            figs.UIAxes_3 = app.UIAxes_6;

            [ISIs] = Spike_Module_Calculate_Plot_ISI(app.Mainapp.Data,app.Mainapp.Data.Spikes.SpikeTimes,Units,Waves,figs,str2double(app.BinNumberISIEditField.Value),str2double(app.MaxISItoshowsEditField.Value));
      
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SpikeWaveformWindowUIFigure = uifigure('Visible', 'off');
            app.SpikeWaveformWindowUIFigure.Name = 'Spike Waveform Window';
            app.SpikeWaveformWindowUIFigure.Icon = 'Logo.png';
            app.SnapshotMenu = uimenu(app.SpikeWaveformWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotMenu.Text = 'Save Snapshot';
            app.PNGMenu = uimenu(app.SaveSnapshotMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.Menu = uimenu(app.SpikeWaveformWindowUIFigure);
            app.UIAxes_1 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_1, 'Z')
            app.UIAxes_2 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_2, 'Z')
            app.UIAxes_3 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_3, 'Z')
            app.UIAxes_4 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_4, 'Z')
            app.UIAxes_5 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_5, 'Z')
            app.UIAxes_6 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_6, 'Z')
            app.UIAxes_7 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_7, 'Z')
            app.UIAxes_8 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_8, 'Z')
            app.UIAxes_9 = uiaxes(app.SpikeWaveformWindowUIFigure);
            zlabel(app.UIAxes_9, 'Z')
            app.Panel_5 = uipanel(app.SpikeWaveformWindowUIFigure);
            app.DeleteButton = uibutton(app.Panel_5, 'push');
            app.DeleteButton.Text = 'Delete';
            app.DeleteUnitDropDownLabel = uilabel(app.Panel_5);
            app.DeleteUnitDropDownLabel.Text = 'Delete Unit';
            app.DeleteUnitDropDown = uidropdown(app.Panel_5);
            app.DeleteUnitDropDown.Items = {''};
            app.DeleteUnitDropDown.Value = '';
            app.Panel_2 = uipanel(app.SpikeWaveformWindowUIFigure);
            app.NumWaveformsEditField_3Label = uilabel(app.Panel_2);
            app.NumWaveformsEditField_3Label.Text = 'Num Waveforms';
            app.NumWaveformsEditField_3 = uieditfield(app.Panel_2, 'text');
            app.NumWaveformsEditField_3.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditField_3ValueChanged, true);
            app.UnitstoShow123EditField_3Label = uilabel(app.Panel_2);
            app.UnitstoShow123EditField_3Label.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField_3 = uieditfield(app.Panel_2, 'text');
            app.UnitstoShow123EditField_3.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditField_3ValueChanged, true);
            app.Panel = uipanel(app.SpikeWaveformWindowUIFigure);
            app.BinNumberISIEditFieldLabel = uilabel(app.Panel);
            app.BinNumberISIEditFieldLabel.Text = 'Bin Number ISI';
            app.BinNumberISIEditField = uieditfield(app.Panel, 'text');
            app.BinNumberISIEditField.ValueChangedFcn = createCallbackFcn(app, @BinNumberISIEditFieldValueChanged, true);
            app.BinNumberISIEditField.Value = '150';
            app.MaxISItoshowsEditFieldLabel = uilabel(app.Panel);
            app.MaxISItoshowsEditFieldLabel.Text = {'Max ISI to show'; '[s]'};
            app.MaxISItoshowsEditField = uieditfield(app.Panel, 'text');
            app.MaxISItoshowsEditField.ValueChangedFcn = createCallbackFcn(app, @MaxISItoshowsEditFieldValueChanged, true);
            app.MaxISItoshowsEditField.Value = '0.150';
            app.KilosortAnalysisWindowLabel = uilabel(app.SpikeWaveformWindowUIFigure);
            app.KilosortAnalysisWindowLabel.Text = 'Kilosort Analysis Window';
            app.Image = uiimage(app.SpikeWaveformWindowUIFigure);
            app.Image.ImageSource = 'Logo.png';
            app.NeuromodToolboxLabel = uilabel(app.SpikeWaveformWindowUIFigure);
            app.NeuromodToolboxLabel.Text = 'Neuromod Toolbox';
            app.CloseButton = uibutton(app.SpikeWaveformWindowUIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.Panel_3 = uipanel(app.SpikeWaveformWindowUIFigure);
            app.NumWaveformsEditField_2Label = uilabel(app.Panel_3);
            app.NumWaveformsEditField_2Label.Text = 'Num Waveforms';
            app.NumWaveformsEditField_2 = uieditfield(app.Panel_3, 'text');
            app.NumWaveformsEditField_2.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditField_2ValueChanged, true);
            app.UnitstoShow123EditField_2Label = uilabel(app.Panel_3);
            app.UnitstoShow123EditField_2Label.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField_2 = uieditfield(app.Panel_3, 'text');
            app.UnitstoShow123EditField_2.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditField_2ValueChanged, true);
            app.Panel_4 = uipanel(app.SpikeWaveformWindowUIFigure);
            app.NumWaveformsEditFieldLabel = uilabel(app.Panel_4);
            app.NumWaveformsEditFieldLabel.Text = 'Num Waveforms';
            app.NumWaveformsEditField = uieditfield(app.Panel_4, 'text');
            app.NumWaveformsEditField.ValueChangedFcn = createCallbackFcn(app, @NumWaveformsEditFieldValueChanged, true);
            app.UnitstoShow123EditFieldLabel = uilabel(app.Panel_4);
            app.UnitstoShow123EditFieldLabel.Text = {'Units to Show'; '(1,2,3)'};
            app.UnitstoShow123EditField = uieditfield(app.Panel_4, 'text');
            app.UnitstoShow123EditField.ValueChangedFcn = createCallbackFcn(app, @UnitstoShow123EditFieldValueChanged, true);
            % Show the figure after all components are created
            app.SpikeWaveformWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Continous_Waveform_Analysis_Window(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.SpikeWaveformWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.SpikeWaveformWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SpikeWaveformWindowUIFigure)
        end
    end
end