classdef Neuromod_Toolbox_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        NeuromodToolboxMainWindowUIFigure  matlab.ui.Figure
        ViewMenu                       matlab.ui.container.Menu
        ChangePlotPropertiesMenu       matlab.ui.container.Menu
        DataPlotMenu                   matlab.ui.container.Menu
        ColormapMenu                   matlab.ui.container.Menu
        parulaMenu                     matlab.ui.container.Menu
        turboMenu                      matlab.ui.container.Menu
        hsvMenu                        matlab.ui.container.Menu
        hotMenu                        matlab.ui.container.Menu
        coolMenu                       matlab.ui.container.Menu
        springMenu                     matlab.ui.container.Menu
        summerMenu                     matlab.ui.container.Menu
        autumnMenu                     matlab.ui.container.Menu
        grayMenu                       matlab.ui.container.Menu
        boneMenu                       matlab.ui.container.Menu
        FontSizeMenu                   matlab.ui.container.Menu
        LineWidthDataMenu              matlab.ui.container.Menu
        LineWidthSpikesMenu            matlab.ui.container.Menu
        LineWidthEventsMenu            matlab.ui.container.Menu
        ColorSpikesMenu                matlab.ui.container.Menu
        ColorEventsMenu                matlab.ui.container.Menu
        BackgorundColorMenu            matlab.ui.container.Menu
        XLabelMenu                     matlab.ui.container.Menu
        YLabelMenu                     matlab.ui.container.Menu
        RESETMenu                      matlab.ui.container.Menu
        TimePlotMenu                   matlab.ui.container.Menu
        TitleMenu_2                    matlab.ui.container.Menu
        FontSizeMenu_2                 matlab.ui.container.Menu
        ColorTimeRectangleMenu         matlab.ui.container.Menu
        ColorEventsMenu_2              matlab.ui.container.Menu
        LineWidthTimeRectangleMenu     matlab.ui.container.Menu
        LineWidthEventsMenu_2          matlab.ui.container.Menu
        BackgroundColorMenu            matlab.ui.container.Menu
        XLabelMenu_2                   matlab.ui.container.Menu
        YLabelMenu_2                   matlab.ui.container.Menu
        RESETMenu_2                    matlab.ui.container.Menu
        SpikePlotAppearanceMenu        matlab.ui.container.Menu
        PlotasPointsMenu               matlab.ui.container.Menu
        PlotasWaveformsMenu            matlab.ui.container.Menu
        SaveCostumAppearanceMenu       matlab.ui.container.Menu
        DeleteCostumAppearanceMenu     matlab.ui.container.Menu
        FullscreenMenu                 matlab.ui.container.Menu
        WindowedMenu                   matlab.ui.container.Menu
        SnapshotMenu                   matlab.ui.container.Menu
        SaveSnapshotofMainWindowMenu   matlab.ui.container.Menu
        PNGMenu                        matlab.ui.container.Menu
        JPGMenu                        matlab.ui.container.Menu
        ManageDatasetMenu              matlab.ui.container.Menu
        ViewExportorDeleteDataComponentsMenu  matlab.ui.container.Menu
        AutorunAnalysisMenu            matlab.ui.container.Menu
        AutorunManagerWindowMenu       matlab.ui.container.Menu
        ManageModulesMenu              matlab.ui.container.Menu
        SwitchAddModulesMenu           matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        MainPlotAnalysisModulePanel    matlab.ui.container.Panel
        RUNButton_5                    matlab.ui.control.Button
        ListBox_6                      matlab.ui.control.ListBox
        ContinousDataModulePanel       matlab.ui.container.Panel
        RUNButton_3                    matlab.ui.control.Button
        ListBox_3                      matlab.ui.control.ListBox
        ManageDatasetPanel             matlab.ui.container.Panel
        RUNButton_4                    matlab.ui.control.Button
        ListBox_5                      matlab.ui.control.ListBox
        EventDataModulePanel           matlab.ui.container.Panel
        ListBox_2                      matlab.ui.control.ListBox
        RUNButton_2                    matlab.ui.control.Button
        SpikeModulePanel_2             matlab.ui.container.Panel
        RUNButton                      matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        RecordingandDatasetInformationLabel  matlab.ui.control.Label
        NeuroModLabel                  matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        MainWindowLabel                matlab.ui.control.Label
        TextArea                       matlab.ui.control.TextArea
        RightPanel                     matlab.ui.container.Panel
        ChannelandPlotControlPanel     matlab.ui.container.Panel
        DropDown_2                     matlab.ui.control.DropDown
        DropDown                       matlab.ui.control.DropDown
        EventChannelDropDown           matlab.ui.control.DropDown
        EventSelectionLabel            matlab.ui.control.Label
        PlotSelectionLabel             matlab.ui.control.Label
        ChannelSelectionLabel          matlab.ui.control.Label
        ChannelSelectionEditField      matlab.ui.control.EditField
        DataLineSpacingLabel           matlab.ui.control.Label
        Slider                         matlab.ui.control.Slider
        TimeSpanandAxisControlPanel    matlab.ui.container.Panel
        IncreaseDecreaseTimeLabel      matlab.ui.control.Label
        PlaybackLabel                  matlab.ui.control.Label
        ForwardLabel                   matlab.ui.control.Label
        BackwardLabel                  matlab.ui.control.Label
        TimetoManipulateMainPlotLabel  matlab.ui.control.Label
        TimeSpanControlDropDown        matlab.ui.control.DropDown
        PauseButton                    matlab.ui.control.Button
        PlayButton                     matlab.ui.control.Button
        Button_3                       matlab.ui.control.Button
        Button_4                       matlab.ui.control.Button
        CurrentTimeSpanLabel           matlab.ui.control.Label
        TimeRangeViewBox               matlab.ui.control.EditField
        Button_2                       matlab.ui.control.Button
        Button                         matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu2                   matlab.ui.container.ContextMenu
        ChangeTimeSpanMenu             matlab.ui.container.Menu
        ChangeMaxTimeSpanMenu          matlab.ui.container.Menu
        ContextMenu3                   matlab.ui.container.ContextMenu
        ContextMenu4                   matlab.ui.container.ContextMenu
        ChangeChannelSpacingRangeMenu  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    %% Define shared variables in app object
    properties (Access = public)
        executableFolder = []; %% Saves path to GUI
        Data = []; % Structure holding Data
        PlotInfo = [];
        ChannelTextHandle = []; % Handle that saves the channel nr the user clicked on in the data plot
        ChannelSelection = []; % Variable saving Channels Selected in Main window
        DefaultTimeRangeDataPlot = []; % Hold time range currently selected to plot data (in amounts of samples)
        TimeLimit = []; % Min and Max number of samples allowed to be showed in plot
        CurrentTimePoints = []; % Current number of settings selected in the time plot and viewed in the data plot
        CheckSuccesful_Extraction = []; % Variable that saves a 1 if data extraction was succesful, 0 if not. This variable is set in the data extraction module GUI
        CheckPlayMovie = [];
        rectangleHandle = []; % Save handle of rectangle in time plot to update fast
        PlotComponents = [] % Captures what the user wants to display in the plot
        
        BandPowerResults = [];
        FlagPlotDownsampledData = 0;
        FlagPlotRawData = 1;

        PlotEvents = "no";
        Plotspikes = "no";
        TwoORThreeD = "TwoD"; % standard 2d plots when possible

        PlotLineSpacing = []; % Info from slider how much channel should be apart
        Channelrange = []; % Info from GUI which channel are selected
        tempcolorMapset = []; % Holding result Eval function integrating GUI selection. This is used for the plot functions!
        tempcolorMap = "parula";
        CurrentEventChannel = [];

        PSTHApp = [];
        CSDApp = [];
        SpectralEstApp = [];
        LastPlot = [];
        PowerSpecResults = [];

        PlotAppearance = [];
        SpikePlotType = "Points";

        CurrentPlotData = [];

        CurrentModules = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %%% Put GUI in the center of the window (with adaptive screen resolution)    
            movegui(app.NeuromodToolboxMainWindowUIFigure, 'center');

            %% Get Path from which the current GUI object is executed
            if isdeployed 
                % User is running an executable in standalone mode.
                if ispc
                    [~, result] = system('set PATH'); % Windows-specific
                    app.executableFolder = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
                elseif isunix
                    [~, result] = system('echo $PATH'); % macOS/Linux
                    app.executableFolder = fileparts(result); % Assuming you need the folder
                end
            else
                % User is running an m-file from the MATLAB integrated development environment (regular MATLAB).
                tmp_path = mfilename('fullpath');
                [app.executableFolder, ~, ~] = fileparts(tmp_path);
            end
            
            app.CurrentTimePoints = uint64(app.CurrentTimePoints);
            %%% Set the Path to the GUI
            cd(app.executableFolder);
            
            %%% Add Path to Matlab automatically
            folder = fileparts(which(mfilename)); 
            % Add that folder plus all subfolders to the path.
            addpath(genpath(folder));

            %% Initiate Modules

            [~,app] = All_Module_Items(app,1,[]);

        end

        % Value changed function: ChannelSelectionEditField
        function ChannelSelectionEditFieldValueChanged(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            
            if strcmp(app.DropDown.Value,'Preprocessed Data') && isfield(app.Data,'Preprocessed')
                NrChannel = size(app.Data.Preprocessed,1);
            elseif strcmp(app.DropDown.Value,'Raw Data') == 1 && isfield(app.Data,'Raw')
                NrChannel = size(app.Data.Raw,1);
            end

            [app.ChannelSelectionEditField.Value] = Utility_SimpleCheckInputs(app.ChannelSelectionEditField.Value,"Two",strcat('1,',num2str(NrChannel)),1,0);
                        
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","Static",app.PlotEvents,app.Plotspikes);

            %% Show mV scale if only one channel shown
            commaindicie = find(app.ChannelSelectionEditField.Value==',');
            SelectedChannel(1) = str2double(app.ChannelSelectionEditField.Value(1:commaindicie(1)-1));
            SelectedChannel(2) = str2double(app.ChannelSelectionEditField.Value(commaindicie(1)+1:end));

            if SelectedChannel(1)==SelectedChannel(2)
                app.UIAxes.YTickMode = 'auto';
                app.UIAxes.YTickLabelMode = 'auto';
                ylabel(app.UIAxes,"Signal [mV]");
            else
                app.UIAxes.YTick = [];         % Remove Y ticks
                app.UIAxes.YTickLabel = {};    % Remove Y tick labels
                ylabel(app.UIAxes,"");
            end
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            Organize_Set_MainWindow_TimeRange(app,str2double(app.TimeRangeViewBox.Value(1:end-1)),app.TimeLimit,"Plus")

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","Static",app.PlotEvents,app.Plotspikes);
            
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            Organize_Set_MainWindow_TimeRange(app,str2double(app.TimeRangeViewBox.Value(1:end-1)),app.TimeLimit,"Minus")

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","Static",app.PlotEvents,app.Plotspikes);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            %% Determine new starting time point of data plot
           
            if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                [app] = Organize_Jump_in_Time(app,"Forward",numel(app.Data.TimeDownsampled),str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.DownsampledSampleRate,app.Data.Info.DownsampledSampleRate);
            else
                [app] = Organize_Jump_in_Time(app,"Forward",numel(app.Data.Time),str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.NativeSamplingRate,app.Data.Info.NativeSamplingRate);
            end
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","Static",app.PlotEvents,app.Plotspikes);

         
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            %% Determine new starting time point of data plot

            if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                [app] = Organize_Jump_in_Time(app,"Backwards",numel(app.Data.TimeDownsampled),str2double(app.TimeRangeViewBox.Value(1:end-1)),app.Data.Info.DownsampledSampleRate);
            else
                [app] = Organize_Jump_in_Time(app,"Backwards",numel(app.Data.Time),str2double(app.TimeRangeViewBox.Value(1:end-1)),app.Data.Info.NativeSamplingRate);
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","Static",app.PlotEvents,app.Plotspikes);

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            app.CheckPlayMovie = 1;
            
            if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                TimeRangeofPlot = str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.DownsampledSampleRate;
                Maxtime = numel(app.Data.TimeDownsampled);
            else
                TimeRangeofPlot = str2double(app.TimeRangeViewBox.Value(1:end-1))*app.Data.Info.NativeSamplingRate;
                Maxtime = numel(app.Data.Time);
            end
            
            while(app.CheckPlayMovie == 1)
                if app.CurrentTimePoints <= Maxtime-TimeRangeofPlot
                    pause(0.0001);
                    %Add number of samples corresponding to time increase
                    %selected in the checkbox to the current index at which
                    %data is shown

                    if isfield(app.Data.Info,'DownsampleFactor') && strcmp(app.DropDown.Value,'Preprocessed Data')
                        app.CurrentTimePoints = app.CurrentTimePoints + round((str2double(app.TimeRangeViewBox.Value(1:end-1))/33*app.Data.Info.DownsampledSampleRate));
                    else
                        app.CurrentTimePoints = app.CurrentTimePoints + round((str2double(app.TimeRangeViewBox.Value(1:end-1))/33*app.Data.Info.NativeSamplingRate));
                    end
    
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Subsequent","Movie",app.PlotEvents,app.Plotspikes);
                else
                    app.CheckPlayMovie = 0;
                end
            end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
            app.CheckPlayMovie = 0;
        end

        % Menu selected function: parulaMenu
        function parulaMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            app.tempcolorMap = "parula";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: turboMenu
        function turboMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end            
            app.tempcolorMap = "turbo";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: hsvMenu
        function hsvMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "hsv";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: hotMenu
        function hotMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "hot";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: coolMenu
        function coolMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "cool";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: springMenu
        function springMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "spring";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: summerMenu
        function summerMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "summer";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: autumnMenu
        function autumnMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "autumn";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: grayMenu
        function grayMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "gray";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: boneMenu
        function boneMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end     
            app.tempcolorMap = "bone";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                % Plot functions are fully autonomous without needed the app
                % object. It is only needed to get the necessary parameter.
                % Both is combined in one function for convenience.
                %input 2: 1 if plot time, 0 if no time plot necessary
                %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                %input 4: Whether Data plot should run in a movie or not
                Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Value changed function: EventChannelDropDown
        function EventChannelDropDownValueChanged(app, event)
            value = app.EventChannelDropDown.Value;
            
            for i = 1:length(app.Data.Info.EventChannelNames)
                if strcmp(app.Data.Info.EventChannelNames{i},value)
                    app.CurrentEventChannel = i;
                end
            end
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: FullscreenMenu
        function FullscreenMenuSelected(app, event)
            if strcmp(app.NeuromodToolboxMainWindowUIFigure.WindowState, 'normal')
                % Switch to fullscreen
                app.NeuromodToolboxMainWindowUIFigure.WindowState = "fullscreen";
                %set(app, 'WindowState', 'fullscreen');
            end
        end

        % Menu selected function: WindowedMenu
        function WindowedMenuSelected(app, event)
            if strcmp(app.NeuromodToolboxMainWindowUIFigure.WindowState, 'fullscreen')
                % Switch to fullscreen
                app.NeuromodToolboxMainWindowUIFigure.WindowState = "normal";
            end
        end

        % Close request function: NeuromodToolboxMainWindowUIFigure
        function NeuromodToolboxMainWindowUIFigureCloseRequest(app, event)
            delete(app)
           
        end

        % Menu selected function: ChangeMaxTimeSpanMenu
        function ChangeMaxTimeSpanMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            Max_Time_Span_Selection(app,"Max Time");
        end

        % Button pushed function: RUNButton_4
        function RUNButton_4Pushed(app, event)
            
            if strcmp(app.ListBox_5.Value,"Load Raw Recordings")

                %% Initiate basic GUI stuff when data is loaded
                
                %% Open Loading Data Module
                % Data and recording info are saved in app.Data structure
                h = Extract_Data_Window(app);
                % Stop script until the extract data window closes 
                waitfor(h);
                
                if app.CheckSuccesful_Extraction == 1
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
                end

            elseif strcmp(app.ListBox_5.Value,"Load Dataset")

                %% Function to Load GUI Data (.mat files)

                cd(app.executableFolder);

                Load_Data_Window(app);

            elseif strcmp(app.ListBox_5.Value,"Save Dataset")

                if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                    f = msgbox("Warning! No Data was extracted.");
                    return;
                end

                Save_Data_Window(app);
            end
        end

        % Button pushed function: RUNButton_3
        function RUNButton_3Pushed(app, event)
            for i = 1:length(app.CurrentModules.Functions)
                if strcmp(app.ContinousDataModulePanel.Title,app.CurrentModules.Title{i})
                    eval(strcat(app.CurrentModules.Functions{i},"(app,app.ListBox_3.Value);"));
                end
            end
        end

        % Button pushed function: RUNButton_2
        function RUNButton_2Pushed(app, event)
            for i = 1:length(app.CurrentModules.Functions)
                if strcmp(app.EventDataModulePanel.Title,app.CurrentModules.Title{i})
                    eval(strcat(app.CurrentModules.Functions{i},"(app,app.ListBox_2.Value);"));
                end
            end   
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            for i = 1:length(app.CurrentModules.Functions)
                if strcmp(app.SpikeModulePanel_2.Title,app.CurrentModules.Title{i})
                    eval(strcat(app.CurrentModules.Functions{i},"(app,app.ListBox.Value);"));
                end
            end   
        end

        % Menu selected function: SaveSnapshotofMainWindowMenu
        function SaveSnapshotofMainWindowMenuSelected(app, event)
            
        end

        % Menu selected function: PNGMenu
        function PNGMenuSelected(app, event)
            cd(app.executableFolder);

            [file, path] = uiputfile('*.png', 'Save as');

            exportapp(app.NeuromodToolboxMainWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: JPGMenu
        function JPGMenuSelected(app, event)
            cd(app.executableFolder);
            
            [file, path] = uiputfile('*.jpg', 'Save as');

            exportapp(app.NeuromodToolboxMainWindowUIFigure,fullfile(path,file)) 
        end

        % Menu selected function: ViewExportorDeleteDataComponentsMenu
        function ViewExportorDeleteDataComponentsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            Manage_Dataset_Components(app);
        end

        % Button pushed function: RUNButton_5
        function RUNButton_5Pushed(app, event)
            for i = 1:length(app.CurrentModules.Functions)
                if strcmp(app.MainPlotAnalysisModulePanel.Title,app.CurrentModules.Title{i})
                    eval(strcat(app.CurrentModules.Functions{i},"(app,app.ListBox_6.Value);"));
                end
            end
        end

        % Menu selected function: AutorunManagerWindowMenu
        function AutorunManagerWindowMenuSelected(app, event)
            Autorun_Manager_Window(app);
        end

        % Menu selected function: ChangeTimeSpanMenu
        function ChangeTimeSpanMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            Max_Time_Span_Selection(app,"Change Time");
        end

        % Menu selected function: XLabelMenu
        function XLabelMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end

            h = Figure_Property_Change_Window(app.UIAxes,'xlabel',app,"MainWindowDataXlabel");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: YLabelMenu
        function YLabelMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'ylabel',app,"MainWindowDataYlabel");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: FontSizeMenu
        function FontSizeMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'fontsize',app,"MainWindowDataFontSize");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: LineWidthDataMenu
        function LineWidthDataMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'linewidth',app,"MainWindowDataLineWidthData");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: BackgorundColorMenu
        function BackgorundColorMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.MainBackground = selectedColor;
                app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Initial","Static",app.PlotEvents,app.Plotspikes);
            
        end

        % Menu selected function: RESETMenu
        function RESETMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.UIAxes.NextPlot = "replace"; 
            plot(app.UIAxes,0,0);
            app.UIAxes.NextPlot = "add"; 
            app.UIAxes.Box = "off";

            [app.PlotAppearance] = Organize_Set_Standard_PlotAppearance("MainDataPlot",app.PlotAppearance);
            
            app.tempcolorMap = "parula";
            app.tempcolorMapset = eval(strcat(app.tempcolorMap,"(size(app.Data.Raw,1))")); % Example colormap: You can use any other colormap
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
            
            %Take care of potentially changed backgroundcolor
            app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            %app.UIAxes_2.Color = app.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            app.UIAxes.FontSize = app.PlotAppearance.MainWindow.Data.MainFontSize;
            %app.UIAxes_2.FontSize = app.PlotAppearance.MainWindow.Data.TimeFontSize;
        end

        % Menu selected function: LineWidthSpikesMenu
        function LineWidthSpikesMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'LineWidthSpikes',app,"MainWindowDataLineWidthSpikes");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: LineWidthEventsMenu
        function LineWidthEventsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes,'LineWidthEvents',app,"MainWindowDataLineWidthEvent");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: ColorSpikesMenu
        function ColorSpikesMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.MainSpikes = selectedColor;
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: ColorEventsMenu
        function ColorEventsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.MainEvents = selectedColor;
            end

            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: TitleMenu_2
        function TitleMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'title',app,"MainWindowTimeTitle");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: FontSizeMenu_2
        function FontSizeMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'fontsize',app,"MainWindowTimeFontSize");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: LineWidthTimeRectangleMenu
        function LineWidthTimeRectangleMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth',app,"MainWindowTimeLineWidthRectangle");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: LineWidthEventsMenu_2
        function LineWidthEventsMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'linewidth',app,"MainWindowTimeLineWidthEvent");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: BackgroundColorMenu
        function BackgroundColorMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.TimeBackground = selectedColor;
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: XLabelMenu_2
        function XLabelMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'xlabel',app,"MainWindowTimeXlabel");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: YLabelMenu_2
        function YLabelMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            h = Figure_Property_Change_Window(app.UIAxes_2,'ylabel',app,"MainWindowTimeYlabel");
            waitfor(h);
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.NeuromodToolboxMainWindowUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {911, 911};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {339, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: RESETMenu_2
        function RESETMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.UIAxes_2.NextPlot = "replace"; 
            plot(app.UIAxes_2,0,0);
            app.UIAxes_2.NextPlot = "add"; 

            [app.PlotAppearance] = Organize_Set_Standard_PlotAppearance("MainTimePlot",app.PlotAppearance);
            
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
            
            %Take care of potentially changed backgroundcolor
            %app.UIAxes.Color = app.PlotAppearance.MainWindow.Data.Color.MainBackground;
            app.UIAxes_2.Color = app.PlotAppearance.MainWindow.Data.Color.TimeBackground;
            %app.UIAxes.FontSize = app.PlotAppearance.MainWindow.Data.MainFontSize;
            app.UIAxes_2.FontSize = app.PlotAppearance.MainWindow.Data.TimeFontSize;
        end

        % Menu selected function: ColorTimeRectangleMenu
        function ColorTimeRectangleMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.TimeRectangle = selectedColor;
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: ColorEventsMenu_2
        function ColorEventsMenu_2Selected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            % Open color selection dialog
            selectedColor = uisetcolor; 
            
            % Check if user selected a color or canceled the dialog
            if length(selectedColor) == 3
                app.PlotAppearance.MainWindow.Data.Color.TimeEvents = selectedColor;
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: PlotasPointsMenu
        function PlotasPointsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.SpikePlotType = "Points";
            SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
            delete(SpikeHandles(:));
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: PlotasWaveformsMenu
        function PlotasWaveformsMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.SpikePlotType = "Waveforms";
            SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
            delete(SpikeHandles(:));
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Menu selected function: SaveCostumAppearanceMenu
        function SaveCostumAppearanceMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.PlotAppearance,app.executableFolder,"Save");
        end

        % Menu selected function: DeleteCostumAppearanceMenu
        function DeleteCostumAppearanceMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            app.PlotAppearance = Utility_Save_Load_Delete_Plot_Appearance(app.PlotAppearance,app.executableFolder,"Delete");
        end

        % Menu selected function: SwitchAddModulesMenu
        function SwitchAddModulesMenuSelected(app, event)
            Manage_Modules_Window(app);
        end

        % Menu selected function: ColormapMenu
        function ColormapMenuSelected(app, event)
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                f = msgbox("Warning! No Data was extracted.");
                return;
            end

            % Get descending y values for each channel plot
            app.PlotLineSpacing = app.Slider.Value;  % Height between each row plot
            % If data is normalized: Adjust so that raw data and
            % preprocessed data scale is not far off
            if isfield(app.Data.Info, 'Normalize') && strcmp(app.DropDown.Value,'Preprocessed Data')
                app.PlotLineSpacing = app.PlotLineSpacing*10;  % Height between each row plot
            end
            % If data is normalized and high pass filtered: Adjust so that raw data and
            % preprocessed data scale is not far off, difference even
            % bigger than just normalization
            if isfield(app.Data.Info, 'Normalize') && isfield(app.Data.Info, 'FilterMethod') && strcmp(app.DropDown.Value,'Preprocessed Data')
                if strcmp(app.Data.Info.FilterMethod,"High-Pass")
                    app.PlotLineSpacing = app.PlotLineSpacing*10;  % Height between each row plot
                end
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","Static",app.PlotEvents,app.Plotspikes);

            % %Take care of potentially changed backgroundcolor
            % app.UIAxes.Color = app.PlotColor.MainBackgroundColor;
            
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            value = app.DropDown.Value;
            
            if strcmp(value,'Raw Data')
                DataPlotTitle = app.UIAxes.Title;
                if strcmp(DataPlotTitle.String,'Preprocessed Data') && isfield(app.Data.Info,'DownsampleFactor')
                    TimeinSecs = app.Data.TimeDownsampled(app.CurrentTimePoints);
                    % Calculate the absolute differences
                    differences = abs(app.Data.Time - TimeinSecs);
                    
                    % Find the index of the minimum difference
                    [~, app.CurrentTimePoints] = min(differences);

                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
                else
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","Static",app.PlotEvents,app.Plotspikes);
                end

            else % Preprocessed Data

                DataPlotTitle = app.UIAxes.Title;
                if strcmp(DataPlotTitle.String,'Raw Data') && isfield(app.Data.Info,'DownsampleFactor')
                    TimeinSecs = app.Data.Time(app.CurrentTimePoints);
                    % Calculate the absolute differences
                    differences = abs(app.Data.TimeDownsampled - TimeinSecs);
                    
                    % Find the index of the minimum difference
                    [~, app.CurrentTimePoints] = min(differences);

                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
                else
                    %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
                    % Plot functions are fully autonomous without needed the app
                    % object. It is only needed to get the necessary parameter.
                    % Both is combined in one function for convenience.
                    %input 2: 1 if plot time, 0 if no time plot necessary
                    %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
                    %input 4: Whether Data plot should run in a movie or not
                    Organize_Prepare_Plot_and_Extract_GUI_Info(app,0,"Subsequent","Static",app.PlotEvents,app.Plotspikes);
                end

            end

            app.UIAxes.Box = "off";
        end

        % Value changed function: DropDown_2
        function DropDown_2ValueChanged(app, event)
            value = app.DropDown_2.Value;
            
            if strcmp(value,'Events')
                % if already plotted
                if strcmp(app.PlotEvents,"Events")
                    Eventline_handlesData = findobj(app.UIAxes,'Type', 'line', 'Tag', 'Events');
                    if ~isempty(Eventline_handlesData)
                        delete(Eventline_handlesData(:));
                    end
                    Eventline_handlesTime = findobj(app.UIAxes_2, 'Tag','Eventlines');
                    if ~isempty(Eventline_handlesTime)
                        delete(Eventline_handlesTime(:));
                    end
                    app.PlotEvents = "No";
                else % if not plotted so far
                    app.PlotEvents = "Events";
                end
            end

            if strcmp(value,'Spikes')
                
                % if already plotted
                if strcmp(app.Plotspikes,"Spikes")
                    SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                    if ~isempty(SpikeHandles)
                        delete(SpikeHandles(:));
                    end
                    app.Plotspikes = "No";
                else % if not plotted so far
                    app.Plotspikes = "Spikes";
                end
            end

            if strcmp(value,'Non')
                if strcmp(app.PlotEvents,"Events")
                    app.PlotEvents = "No";
                    Eventline_handlesData = findobj(app.UIAxes,'Type', 'line', 'Tag', 'Events');
                    if ~isempty(Eventline_handlesData)
                        delete(Eventline_handlesData(:));
                    end
                    Eventline_handlesTime = findobj(app.UIAxes_2, 'Tag','Eventlines');
                    if ~isempty(Eventline_handlesTime)
                        delete(Eventline_handlesTime(:));
                    end
                end
                if strcmp(app.Plotspikes,"Spikes")
                    app.Plotspikes = "No";
                    SpikeHandles = findobj(app.UIAxes, 'Type', 'line', 'Tag', 'Spikes');
                    if ~isempty(SpikeHandles)
                        delete(SpikeHandles(:));
                    end
                end
            end
            %% Get all necessary Infos from GUI, set time scale based on time window, select data based on this AND plot
            % Plot functions are fully autonomous without needed the app
            % object. It is only needed to get the necessary parameter.
            % Both is combined in one function for convenience.
            %input 2: 1 if plot time, 0 if no time plot necessary
            %input 3: Update time plot = Subsequent; Replot whole time plot = "Initial"
            %input 4: Whether Data plot should run in a movie or not
            Organize_Prepare_Plot_and_Extract_GUI_Info(app,1,"Initial","Static",app.PlotEvents,app.Plotspikes);
        end

        % Clicked callback: DropDown_2
        function DropDown_2Clicked(app, event)
            
            
        end

        % Menu selected function: ChangeChannelSpacingRangeMenu
        function ChangeChannelSpacingRangeMenuSelected(app, event)
            if ~isfield(app.Data,'Raw') && ~isfield(app.Data,'Preprocessed')
                msgbox("Warning! No Data was extracted.");
                return;
            end
            Channel_Spacing_Selection(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.NeuromodToolboxMainWindowUIFigure = uifigure('Visible', 'off');
            app.NeuromodToolboxMainWindowUIFigure.Name = 'Neuromod Toolbox Main Window';
            app.NeuromodToolboxMainWindowUIFigure.Icon = fullfile(pathToMLAPP, 'Modules', 'MISC', 'Images', 'Logo.png');
            app.NeuromodToolboxMainWindowUIFigure.CloseRequestFcn = createCallbackFcn(app, @NeuromodToolboxMainWindowUIFigureCloseRequest, true);
            app.NeuromodToolboxMainWindowUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ViewMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ViewMenu.Text = '   View   ';
            app.ChangePlotPropertiesMenu = uimenu(app.ViewMenu);
            app.ChangePlotPropertiesMenu.Text = 'Change Plot Properties';
            app.DataPlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DataPlotMenu.Text = 'Data Plot';
            app.ColormapMenu = uimenu(app.DataPlotMenu);
            app.ColormapMenu.MenuSelectedFcn = createCallbackFcn(app, @ColormapMenuSelected, true);
            app.ColormapMenu.Text = 'Colormap';
            app.parulaMenu = uimenu(app.ColormapMenu);
            app.parulaMenu.MenuSelectedFcn = createCallbackFcn(app, @parulaMenuSelected, true);
            app.parulaMenu.Text = 'parula';
            app.turboMenu = uimenu(app.ColormapMenu);
            app.turboMenu.MenuSelectedFcn = createCallbackFcn(app, @turboMenuSelected, true);
            app.turboMenu.Text = 'turbo';
            app.hsvMenu = uimenu(app.ColormapMenu);
            app.hsvMenu.MenuSelectedFcn = createCallbackFcn(app, @hsvMenuSelected, true);
            app.hsvMenu.Text = 'hsv';
            app.hotMenu = uimenu(app.ColormapMenu);
            app.hotMenu.MenuSelectedFcn = createCallbackFcn(app, @hotMenuSelected, true);
            app.hotMenu.Text = 'hot';
            app.coolMenu = uimenu(app.ColormapMenu);
            app.coolMenu.MenuSelectedFcn = createCallbackFcn(app, @coolMenuSelected, true);
            app.coolMenu.Text = 'cool';
            app.springMenu = uimenu(app.ColormapMenu);
            app.springMenu.MenuSelectedFcn = createCallbackFcn(app, @springMenuSelected, true);
            app.springMenu.Text = 'spring';
            app.summerMenu = uimenu(app.ColormapMenu);
            app.summerMenu.MenuSelectedFcn = createCallbackFcn(app, @summerMenuSelected, true);
            app.summerMenu.Text = 'summer';
            app.autumnMenu = uimenu(app.ColormapMenu);
            app.autumnMenu.MenuSelectedFcn = createCallbackFcn(app, @autumnMenuSelected, true);
            app.autumnMenu.Text = 'autumn';
            app.grayMenu = uimenu(app.ColormapMenu);
            app.grayMenu.MenuSelectedFcn = createCallbackFcn(app, @grayMenuSelected, true);
            app.grayMenu.Text = 'gray';
            app.boneMenu = uimenu(app.ColormapMenu);
            app.boneMenu.MenuSelectedFcn = createCallbackFcn(app, @boneMenuSelected, true);
            app.boneMenu.Text = 'bone';
            app.FontSizeMenu = uimenu(app.DataPlotMenu);
            app.FontSizeMenu.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenuSelected, true);
            app.FontSizeMenu.Text = 'FontSize';
            app.LineWidthDataMenu = uimenu(app.DataPlotMenu);
            app.LineWidthDataMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthDataMenuSelected, true);
            app.LineWidthDataMenu.Text = 'LineWidth Data';
            app.LineWidthSpikesMenu = uimenu(app.DataPlotMenu);
            app.LineWidthSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthSpikesMenuSelected, true);
            app.LineWidthSpikesMenu.Text = 'LineWidth Spikes';
            app.LineWidthEventsMenu = uimenu(app.DataPlotMenu);
            app.LineWidthEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthEventsMenuSelected, true);
            app.LineWidthEventsMenu.Text = 'LineWidth Events';
            app.ColorSpikesMenu = uimenu(app.DataPlotMenu);
            app.ColorSpikesMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorSpikesMenuSelected, true);
            app.ColorSpikesMenu.Text = 'Color Spikes';
            app.ColorEventsMenu = uimenu(app.DataPlotMenu);
            app.ColorEventsMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorEventsMenuSelected, true);
            app.ColorEventsMenu.Text = 'Color Events';
            app.BackgorundColorMenu = uimenu(app.DataPlotMenu);
            app.BackgorundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgorundColorMenuSelected, true);
            app.BackgorundColorMenu.Text = 'Backgorund Color';
            app.XLabelMenu = uimenu(app.DataPlotMenu);
            app.XLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenuSelected, true);
            app.XLabelMenu.Text = 'XLabel';
            app.YLabelMenu = uimenu(app.DataPlotMenu);
            app.YLabelMenu.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenuSelected, true);
            app.YLabelMenu.Text = 'YLabel';
            app.RESETMenu = uimenu(app.DataPlotMenu);
            app.RESETMenu.MenuSelectedFcn = createCallbackFcn(app, @RESETMenuSelected, true);
            app.RESETMenu.Text = 'RESET';
            app.TimePlotMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.TimePlotMenu.Text = 'Time Plot';
            app.TitleMenu_2 = uimenu(app.TimePlotMenu);
            app.TitleMenu_2.MenuSelectedFcn = createCallbackFcn(app, @TitleMenu_2Selected, true);
            app.TitleMenu_2.Text = 'Title';
            app.FontSizeMenu_2 = uimenu(app.TimePlotMenu);
            app.FontSizeMenu_2.MenuSelectedFcn = createCallbackFcn(app, @FontSizeMenu_2Selected, true);
            app.FontSizeMenu_2.Text = 'FontSize';
            app.ColorTimeRectangleMenu = uimenu(app.TimePlotMenu);
            app.ColorTimeRectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @ColorTimeRectangleMenuSelected, true);
            app.ColorTimeRectangleMenu.Text = 'Color Time Rectangle';
            app.ColorEventsMenu_2 = uimenu(app.TimePlotMenu);
            app.ColorEventsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @ColorEventsMenu_2Selected, true);
            app.ColorEventsMenu_2.Text = 'Color Events';
            app.LineWidthTimeRectangleMenu = uimenu(app.TimePlotMenu);
            app.LineWidthTimeRectangleMenu.MenuSelectedFcn = createCallbackFcn(app, @LineWidthTimeRectangleMenuSelected, true);
            app.LineWidthTimeRectangleMenu.Text = 'LineWidth Time Rectangle';
            app.LineWidthEventsMenu_2 = uimenu(app.TimePlotMenu);
            app.LineWidthEventsMenu_2.MenuSelectedFcn = createCallbackFcn(app, @LineWidthEventsMenu_2Selected, true);
            app.LineWidthEventsMenu_2.Text = 'LineWidth Events';
            app.BackgroundColorMenu = uimenu(app.TimePlotMenu);
            app.BackgroundColorMenu.MenuSelectedFcn = createCallbackFcn(app, @BackgroundColorMenuSelected, true);
            app.BackgroundColorMenu.Text = 'Background Color';
            app.XLabelMenu_2 = uimenu(app.TimePlotMenu);
            app.XLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @XLabelMenu_2Selected, true);
            app.XLabelMenu_2.Text = 'XLabel';
            app.YLabelMenu_2 = uimenu(app.TimePlotMenu);
            app.YLabelMenu_2.MenuSelectedFcn = createCallbackFcn(app, @YLabelMenu_2Selected, true);
            app.YLabelMenu_2.Text = 'YLabel';
            app.RESETMenu_2 = uimenu(app.TimePlotMenu);
            app.RESETMenu_2.MenuSelectedFcn = createCallbackFcn(app, @RESETMenu_2Selected, true);
            app.RESETMenu_2.Text = 'RESET';
            app.SpikePlotAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SpikePlotAppearanceMenu.Text = 'Spike Plot Appearance';
            app.PlotasPointsMenu = uimenu(app.SpikePlotAppearanceMenu);
            app.PlotasPointsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotasPointsMenuSelected, true);
            app.PlotasPointsMenu.Text = 'Plot as Points';
            app.PlotasWaveformsMenu = uimenu(app.SpikePlotAppearanceMenu);
            app.PlotasWaveformsMenu.MenuSelectedFcn = createCallbackFcn(app, @PlotasWaveformsMenuSelected, true);
            app.PlotasWaveformsMenu.Text = 'Plot as Waveforms';
            app.SaveCostumAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.SaveCostumAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveCostumAppearanceMenuSelected, true);
            app.SaveCostumAppearanceMenu.Text = 'Save Costum Appearance';
            app.DeleteCostumAppearanceMenu = uimenu(app.ChangePlotPropertiesMenu);
            app.DeleteCostumAppearanceMenu.MenuSelectedFcn = createCallbackFcn(app, @DeleteCostumAppearanceMenuSelected, true);
            app.DeleteCostumAppearanceMenu.Text = 'Delete Costum Appearance';
            app.FullscreenMenu = uimenu(app.ViewMenu);
            app.FullscreenMenu.MenuSelectedFcn = createCallbackFcn(app, @FullscreenMenuSelected, true);
            app.FullscreenMenu.Text = 'Fullscreen';
            app.WindowedMenu = uimenu(app.ViewMenu);
            app.WindowedMenu.MenuSelectedFcn = createCallbackFcn(app, @WindowedMenuSelected, true);
            app.WindowedMenu.Text = 'Windowed';
            app.SnapshotMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.SnapshotMenu.Text = '   Snapshot   ';
            app.SaveSnapshotofMainWindowMenu = uimenu(app.SnapshotMenu);
            app.SaveSnapshotofMainWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveSnapshotofMainWindowMenuSelected, true);
            app.SaveSnapshotofMainWindowMenu.Text = 'Save Snapshot of Main Window';
            app.PNGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.PNGMenu.MenuSelectedFcn = createCallbackFcn(app, @PNGMenuSelected, true);
            app.PNGMenu.Text = 'PNG';
            app.JPGMenu = uimenu(app.SaveSnapshotofMainWindowMenu);
            app.JPGMenu.MenuSelectedFcn = createCallbackFcn(app, @JPGMenuSelected, true);
            app.JPGMenu.Text = 'JPG';
            app.ManageDatasetMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ManageDatasetMenu.Text = '   Manage Dataset   ';
            app.ViewExportorDeleteDataComponentsMenu = uimenu(app.ManageDatasetMenu);
            app.ViewExportorDeleteDataComponentsMenu.MenuSelectedFcn = createCallbackFcn(app, @ViewExportorDeleteDataComponentsMenuSelected, true);
            app.ViewExportorDeleteDataComponentsMenu.Text = '   View, Export or Delete Data Components';
            app.AutorunAnalysisMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.AutorunAnalysisMenu.Text = '   Autorun Analysis   ';
            app.AutorunManagerWindowMenu = uimenu(app.AutorunAnalysisMenu);
            app.AutorunManagerWindowMenu.MenuSelectedFcn = createCallbackFcn(app, @AutorunManagerWindowMenuSelected, true);
            app.AutorunManagerWindowMenu.Text = 'Autorun Manager Window';
            app.ManageModulesMenu = uimenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ManageModulesMenu.Text = '   Manage Modules   ';
            app.SwitchAddModulesMenu = uimenu(app.ManageModulesMenu);
            app.SwitchAddModulesMenu.MenuSelectedFcn = createCallbackFcn(app, @SwitchAddModulesMenuSelected, true);
            app.SwitchAddModulesMenu.Text = 'Switch/Add Modules';
            app.GridLayout = uigridlayout(app.NeuromodToolboxMainWindowUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Editable = 'off';
            app.MainWindowLabel = uilabel(app.LeftPanel);
            app.MainWindowLabel.Text = 'Main Window';
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Modules', 'MISC', 'Images', 'Logo.png');
            app.NeuroModLabel = uilabel(app.LeftPanel);
            app.NeuroModLabel.Text = {'NeuroMod '; ''};
            app.RecordingandDatasetInformationLabel = uilabel(app.LeftPanel);
            app.RecordingandDatasetInformationLabel.Text = 'Recording and Dataset Information';
            app.SpikeModulePanel_2 = uipanel(app.LeftPanel);
            app.SpikeModulePanel_2.Title = 'Spike Module';
            app.ListBox = uilistbox(app.SpikeModulePanel_2);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.RUNButton = uibutton(app.SpikeModulePanel_2, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Enable = 'off';
            app.RUNButton.Text = 'RUN';
            app.EventDataModulePanel = uipanel(app.LeftPanel);
            app.EventDataModulePanel.Title = 'Event Data Module';
            app.RUNButton_2 = uibutton(app.EventDataModulePanel, 'push');
            app.RUNButton_2.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_2Pushed, true);
            app.RUNButton_2.Enable = 'off';
            app.RUNButton_2.Text = 'RUN';
            app.ListBox_2 = uilistbox(app.EventDataModulePanel);
            app.ListBox_2.Items = {};
            app.ListBox_2.Value = {};
            app.ManageDatasetPanel = uipanel(app.LeftPanel);
            app.ManageDatasetPanel.Title = 'Manage Dataset';
            app.ListBox_5 = uilistbox(app.ManageDatasetPanel);
            app.ListBox_5.Items = {'Load Raw Recordings', 'Save Dataset', 'Load Dataset'};
            app.ListBox_5.Value = 'Load Raw Recordings';
            app.RUNButton_4 = uibutton(app.ManageDatasetPanel, 'push');
            app.RUNButton_4.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_4Pushed, true);
            app.RUNButton_4.Text = 'RUN';
            app.ContinousDataModulePanel = uipanel(app.LeftPanel);
            app.ContinousDataModulePanel.Title = 'Continous Data Module';
            app.ListBox_3 = uilistbox(app.ContinousDataModulePanel);
            app.ListBox_3.Items = {};
            app.ListBox_3.Value = {};
            app.RUNButton_3 = uibutton(app.ContinousDataModulePanel, 'push');
            app.RUNButton_3.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_3Pushed, true);
            app.RUNButton_3.Enable = 'off';
            app.RUNButton_3.Text = 'RUN';
            app.MainPlotAnalysisModulePanel = uipanel(app.LeftPanel);
            app.MainPlotAnalysisModulePanel.Title = 'Main Plot Analysis Module';
            app.ListBox_6 = uilistbox(app.MainPlotAnalysisModulePanel);
            app.ListBox_6.Items = {};
            app.ListBox_6.Value = {};
            app.RUNButton_5 = uibutton(app.MainPlotAnalysisModulePanel, 'push');
            app.RUNButton_5.ButtonPushedFcn = createCallbackFcn(app, @RUNButton_5Pushed, true);
            app.RUNButton_5.Enable = 'off';
            app.RUNButton_5.Text = 'RUN';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            app.UIAxes.YTickLabel = '';
            app.UIAxes_2 = uiaxes(app.RightPanel);
            app.UIAxes_2.YTick = [];
            app.TimeSpanandAxisControlPanel = uipanel(app.RightPanel);
            app.TimeSpanandAxisControlPanel.Title = 'Time Span and Axis Control';
            app.Button = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '-';
            app.TimeRangeViewBox = uieditfield(app.TimeSpanandAxisControlPanel, 'text');
            app.TimeRangeViewBox.Editable = 'off';
            app.CurrentTimeSpanLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.CurrentTimeSpanLabel.Text = {'Current'; 'Time Span:'};
            app.Button_4 = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '<<';
            app.Button_3 = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '>>';
            app.PlayButton = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.PauseButton = uibutton(app.TimeSpanandAxisControlPanel, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Text = 'Pause';
            app.TimeSpanControlDropDown = uidropdown(app.TimeSpanandAxisControlPanel);
            app.TimeSpanControlDropDown.Items = {'0.01s', '0.1s', '0.5s', '1s'};
            app.TimeSpanControlDropDown.Value = '0.1s';
            app.TimetoManipulateMainPlotLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.TimetoManipulateMainPlotLabel.Text = {'Time to Manipulate'; 'Main Plot'};
            app.BackwardLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.BackwardLabel.Text = 'Backward';
            app.ForwardLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.ForwardLabel.Text = 'Forward';
            app.PlaybackLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.PlaybackLabel.Text = 'Playback';
            app.IncreaseDecreaseTimeLabel = uilabel(app.TimeSpanandAxisControlPanel);
            app.IncreaseDecreaseTimeLabel.Text = {'Increase/'; 'Decrease Time'};
            app.ChannelandPlotControlPanel = uipanel(app.RightPanel);
            app.ChannelandPlotControlPanel.Title = 'Channel and Plot Control';
            app.Slider = uislider(app.ChannelandPlotControlPanel);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.DataLineSpacingLabel = uilabel(app.ChannelandPlotControlPanel);
            app.DataLineSpacingLabel.Text = 'Data Line Spacing';
            app.ChannelSelectionEditField = uieditfield(app.ChannelandPlotControlPanel, 'text');
            app.ChannelSelectionEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelSelectionEditFieldValueChanged, true);
            app.ChannelSelectionLabel = uilabel(app.ChannelandPlotControlPanel);
            app.ChannelSelectionLabel.Text = 'Channel Selection';
            app.PlotSelectionLabel = uilabel(app.ChannelandPlotControlPanel);
            app.PlotSelectionLabel.Text = 'Plot Selection';
            app.EventSelectionLabel = uilabel(app.ChannelandPlotControlPanel);
            app.EventSelectionLabel.Text = 'Event Selection';
            app.EventChannelDropDown = uidropdown(app.ChannelandPlotControlPanel);
            app.EventChannelDropDown.Items = {''};
            app.EventChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @EventChannelDropDownValueChanged, true);
            app.EventChannelDropDown.Value = '';
            app.DropDown = uidropdown(app.ChannelandPlotControlPanel);
            app.DropDown.Items = {'Raw Data'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'Raw Data';
            app.DropDown_2 = uidropdown(app.ChannelandPlotControlPanel);
            app.DropDown_2.Items = {'Non'};
            app.DropDown_2.ValueChangedFcn = createCallbackFcn(app, @DropDown_2ValueChanged, true);
            app.DropDown_2.ClickedFcn = createCallbackFcn(app, @DropDown_2Clicked, true);
            app.DropDown_2.Value = 'Non';
            app.ContextMenu2 = uicontextmenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ChangeTimeSpanMenu = uimenu(app.ContextMenu2);
            app.ChangeTimeSpanMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeTimeSpanMenuSelected, true);
            app.ChangeTimeSpanMenu.Text = 'Change Time Span';
            app.ChangeMaxTimeSpanMenu = uimenu(app.ContextMenu2);
            app.ChangeMaxTimeSpanMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeMaxTimeSpanMenuSelected, true);
            app.ChangeMaxTimeSpanMenu.Text = 'Change Max Time Span';
            % Assign app.ContextMenu2
            app.TimeRangeViewBox.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.NeuromodToolboxMainWindowUIFigure);
            % Assign app.ContextMenu3
            app.UIAxes.ContextMenu = app.ContextMenu3;
            app.ContextMenu4 = uicontextmenu(app.NeuromodToolboxMainWindowUIFigure);
            app.ChangeChannelSpacingRangeMenu = uimenu(app.ContextMenu4);
            app.ChangeChannelSpacingRangeMenu.MenuSelectedFcn = createCallbackFcn(app, @ChangeChannelSpacingRangeMenuSelected, true);
            app.ChangeChannelSpacingRangeMenu.Text = 'Change Channel Spacing Range';
            % Assign app.ContextMenu4
            app.Slider.ContextMenu = app.ContextMenu4;
            % Show the figure after all components are created
            app.NeuromodToolboxMainWindowUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Neuromod_Toolbox_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.NeuromodToolboxMainWindowUIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.NeuromodToolboxMainWindowUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.NeuromodToolboxMainWindowUIFigure)
        end
    end
end